classdef dataViewer_PP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        dataViewerUI                matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        UpdateCellListMenu          matlab.ui.container.Menu
        OpenNotesMenu               matlab.ui.container.Menu
        AnalysisMenu                matlab.ui.container.Menu
        PipetteSolutionMenu         matlab.ui.container.Menu
        CesiumMenu                  matlab.ui.container.Menu
        PotassiumMenu               matlab.ui.container.Menu
        HEPEsMenu                   matlab.ui.container.Menu
        AddPairMenu                 matlab.ui.container.Menu
        AddOtherAmpMenu             matlab.ui.container.Menu
        SetFiringRateWindowMenu     matlab.ui.container.Menu
        FlagEpochsMenu              matlab.ui.container.Menu
        RedMenu                     matlab.ui.container.Menu
        OrangeMenu                  matlab.ui.container.Menu
        YellowMenu                  matlab.ui.container.Menu
        GreenMenu                   matlab.ui.container.Menu
        BlueMenu                    matlab.ui.container.Menu
        PurpleMenu                  matlab.ui.container.Menu
        PrintFlagsMenu              matlab.ui.container.Menu
        EditFlagsMenu               matlab.ui.container.Menu
        SelectButton                matlab.ui.control.Button
        spikeSwitch                 matlab.ui.control.Switch
        SpikeDetectorDropDown       matlab.ui.control.DropDown
        SpikeDetectionLabel         matlab.ui.control.Label
        SetPropsButton              matlab.ui.control.Button
        AnalyzeLabel                matlab.ui.control.Label
        IDButton                    matlab.ui.control.Button
        SolutionUnspecifiedLabel    matlab.ui.control.Label
        FillButton                  matlab.ui.control.Button
        AVOIDCheckBox               matlab.ui.control.CheckBox
        DefineAnalysisLabel         matlab.ui.control.Label
        WillAnalyzeEditField        matlab.ui.control.EditField
        WillAnalyzeEditFieldLabel   matlab.ui.control.Label
        AnalysisTypeDropDown        matlab.ui.control.DropDown
        AnalysisTypeDropDownLabel   matlab.ui.control.Label
        RecordingTypeDropDown       matlab.ui.control.DropDown
        RecordingTypeDropDownLabel  matlab.ui.control.Label
        PlotRawDataButton           matlab.ui.control.Button
        ViewDetailsButton           matlab.ui.control.Button
        EpochEditField              matlab.ui.control.EditField
        EpochEditFieldLabel         matlab.ui.control.Label
        ViewEpochsLabel             matlab.ui.control.Label
        AnalysisTagsListBox         matlab.ui.control.ListBox
        AnalysisTagsListBoxLabel    matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        CellIDDataViewerLabel       matlab.ui.control.Label
        SuffixEditField             matlab.ui.control.EditField
        SuffixEditFieldLabel        matlab.ui.control.Label
        RunAnalysisButton           matlab.ui.control.Button
        RevertButton                matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        SaveFigureButton            matlab.ui.control.Button
        ContextMenu                 matlab.ui.container.ContextMenu
        PlotOptionsMenu             matlab.ui.container.Menu
        ShowSpikeDetectionMenu      matlab.ui.container.Menu
        CalculateMaxFiringRateForSingleEpochMenu  matlab.ui.container.Menu
        ConditionsContextMenu       matlab.ui.container.ContextMenu
        ClearMenu                   matlab.ui.container.Menu
    end

    
    properties (Access = public)
        cellID %cellID input argument (string)
        originalData %data input argument
        CellParameters %.mat structure that is either loaded from path or created and saved to proper location
    end
    
    properties (Access = private)
        analysisFigure % figure handle for most recent figure produced by analysis
        lastAnalysisType %name of the last analysisType done (used in saving figure)
        lastRecordingTypeAndSuffix %name of last recording type and suffix (used in saving figure)
        cellListPathLocal %path of cell list on the local device (this contains the fName as well)
        cellList %used temporarily to hold the batch list while saving
        analysesPerformed %cell array that lists analyses done since last save and/or revert. Each list item is a cell array in itself where the first element is str of the recording type and the analysisType and the second element is the recordingTypeAndSuffix
        lastAnalysisObject % analysisObject returned by an analysis
        maxFiringRateWindowSize = 50; %sliding window length for max firing rate calculations
        callerObj %Obj for the pipeline app that launches this data viewer
        spikeDetector %spike detector object
        spikeDetectionFigHandle %handle for the ui figure that opens when the set params button is pressed
        selectionState = 0; %keeps track of whether the "select" button will select all or deselect all on the next click
    end
    
    
    methods (Access = private) %helper functions
        
        function fillCellParameters(app, processedDataStruct)
            %Here we add the analysis that was just done to the
            %CellParameters structure, which contains all analyses done for
            %this cell so far.
            
            %Two field names give the analysis specificity: the first is
            %the "analysis type" (e.g. flash, moving grating) and the
            %second is the "recording type" (e.g. extracellular). These names
            %are the first and second branches of processedDataStruct,
            %respectively, and we'd like to preserve them. However, we can
            %save multiple repetitions of an analysis seperately if a
            %suffix has been speficied by the user. The suffix will be
            %added on to the "recording type" field name (e.g.
            %extracellular_2).
            justAnalyzed = processedDataStruct; %simple rename of data struct to add
            
            %get analysis and recording types, along with the suffix if it
            %exists
            fieldToAdd1 = fieldnames(justAnalyzed);
            analysisType = fieldToAdd1{1};
            fieldToAdd2 = fieldnames(justAnalyzed.(analysisType));
            recordingType = fieldToAdd2{1};
            recordingTypeAndSuffix = strcat(recordingType, app.SuffixEditField.Value);
            
            %Ask if the analysis type + recording type has been analyzed
            %before
            if isfield(app.CellParameters, 'Analyses_Completed')...
                    && isfield(app.CellParameters.Analyses_Completed, analysisType)...
                    && isfield(app.CellParameters.Analyses_Completed.(analysisType), recordingTypeAndSuffix)
            %Notify user if they're duplicating a branch of the
            %structure. Permanent overwriting will happen if they save,
            %but they still have the option of "reverting" after
            %pressing okay
            waitfor(msgbox(['This analysis has been performed. Previously saved data will' ...
                ' be permamently overwritten unless the "Revert" button is pressed.'], 'Overwrite Warning'))
            end
            
            %fill cell parameters
            app.CellParameters.Analyses_Completed.(analysisType).(recordingTypeAndSuffix).Analysis_Results =...
                justAnalyzed.(analysisType).(recordingType);
            app.CellParameters.Analyses_Completed.(analysisType).(recordingTypeAndSuffix).meta  =...
                justAnalyzed.(analysisType).(recordingType).meta;
            
            %add avoid tag to the specific analysis if needed
            app.CellParameters.Analyses_Completed.(analysisType).(recordingTypeAndSuffix).meta.AVOID = app.AVOIDCheckBox.Value;
            
            %add pipette solution if needed, add it to the meta data
            solution = 'Unspecified';
            if app.HEPEsMenu.Checked
                solution = 'HEPEs';
            elseif app.CesiumMenu.Checked
                solution = 'Cesium';
            elseif app.PotassiumMenu.Checked
                solution = 'Potassium';
            end
            app.CellParameters.Analyses_Completed.(analysisType).(recordingTypeAndSuffix).meta.pipetteSolution = solution;
            
            %temporarily save some data about the last analysis done
            app.lastAnalysisType = analysisType;
            app.lastRecordingTypeAndSuffix = recordingTypeAndSuffix;
        end
        
        
        function updateCellList(app)
            %cellList is the batch data for the current project
            %it is an Nx2 cell array. Column 1 contains the names of
            %all the cells that have been analyzed for this project. Column 2 contains a
            %structure for that cell with information about it
            
            %load seperately on each save so you get the most up to date
            %version (if you load cell list in startup then multiple cells
            %being analayzed at once will have old copies of the file)
            if ~app.UpdateCellListMenu.Checked
                disp('Batch updates are turned off')
                return
            end
            app.cellListPathLocal = changePathSlashesUnix(app.cellListPathLocal);
            disp(app.cellListPathLocal);
            [~, cellIndex] = app.checkCellList(app.cellListPathLocal, 1);
            
            %add all the analyses listed in app.analysesPerformed to the
            %cellList
            if cellIndex == 0 %cell was not found in cellList
                cellIndex = size(app.cellList, 1)+1;
            end
            
            %add on the relative path from the batch file to the cell
            %parameters file just for the version of cell parameters that
            %is saved in the batch list
            
            relativePathFromBatch = relativepath(app.CellParameters.fileLocationLocal, app.cellListPathLocal);
            cp = app.CellParameters;
            cp.relativePathFromBatchToCellParameters = relativePathFromBatch;
            app.cellList{cellIndex, 1} = app.cellID;
            app.cellList{cellIndex, 2} = cp;
            
            cellList = app.cellList;
            save(app.cellListPathLocal, "cellList")
            
            %clear app.analysesPerformed, it will be refilled if subsequent
            %analyses are run
            app.analysesPerformed = {};
            
            %document the project update in the projectInfo file
            app.callerObj.projectInfo.lastUpdate = datetime();
            projectInfo = app.callerObj.projectInfo;
            fNameInfo = [projectInfo.projectName '_physiologyInfo.mat'];
            p = fileparts(app.cellListPathLocal);
            save(fullfile(p, fNameInfo), 'projectInfo')
            disp('Saved batch data')
        end
        
        function [found, cellIndex] = checkCellList(app, fName, addCellList)
            found = 0; cellIndex = 0;
            load(fName); %loads variable with name "cellList" to the workspace (Nx2 cell array, column 1 = strings of cell names, column 2 = structs for each cell saying if it has been analyzed).
            if addCellList
                app.cellList = cellList;
            end
            
            for i = 1:size(app.cellList, 1)
                if strcmp(app.cellID, app.cellList{i, 1})
                    cellIndex = i;
                    break
                end
            end
            if cellIndex > 0
                found = 1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, cell_id, callerObj)
            %Inputs are: data = data structure formed by master script
            %cell_id = string corresponding to cell's name as it appears as
            %a field in the data structure (e.g. 'SH13Lc1')
            app.dataViewerUI.Color = rand([1, 3])*0.1+0.9;
            app.CellIDDataViewerLabel.Text = [cell_id ' - Data Viewer'];
            expGroup_id = data.(cell_id).epochLog(1,:);
            startStopIndex = data.(cell_id).epochLog(2, :);
            d = cell(numel(expGroup_id), 6); %initialize table
            
            %build cell array d w/ data that you'll want to present in the
            %gui (UITable)
            for i = 1:numel(expGroup_id)
                d{i, 1} = false;
                d{i, 2} = expGroup_id{i};
                startIndex = startStopIndex{i}(1);%start index
                stopIndex = startStopIndex{i}(2);%stop index
                numEpochs = stopIndex-startIndex + 1;
                try
                    onlineAnalysisType = data.(cell_id).epochs(startIndex).meta.onlineAnalysis;
                catch
                    onlineAnalysisType = 'unknown';
                end
                d{i, 3} = numEpochs;
                d{i, 4} = startIndex;
                d{i, 5} = stopIndex;
                d{i, 6} = onlineAnalysisType;
            end
            
            app.UITable.Data = d; %fill UITable with data for this cell
            app.UITable.ColumnName = {'Analyze', 'Protocol', '# Epochs', 'Start Index',...
                'Stop Index', 'Online Analysis'};
            app.UITable.ColumnEditable = true;
            
            app.cellID = cell_id;
            app.originalData = data;
            
            app.EpochEditField.Value = 'View an Epoch #';
            
            % Check for existence of CellParameters structure in file
            % location. If it doesn't exist initialize it, otherwise just
            % load it.
            cp_path = fullfile(data.(cell_id).fileLocation, cell_id, [cell_id, '_Analysis.mat']); %local path on the user's machine
            
            %if the cell already exists:
            if exist(cp_path) == 2
                load(cp_path); %loads to workspace as variable called "CellParameters"
                app.CellParameters = CellParameters;
                app.CellParameters.fileLocationLocal = changePathSlashesUnix(data.(cell_id).fileLocation); %reset to local location on this machine
                app.CellParameters.relativePathFromPipeline = changePathSlashesUnix(data.(cell_id).relativePathFromPipeline); %reset to local location on this machine
                app.CellParameters.coordinates = data.(cell_id).coordinates;
                
                %ask if the user would like to use the new or old recording
                %information
                indx = listdlg("PromptString", ['Select recording info for cell ' cell_id],...
                    "ListString", {'Use Old', 'Use New'},'SelectionMode', 'single', 'ListSize', [300, 50]);
                if indx == 2
                    app.CellParameters.RecordingInformation = callerObj.RecordingInformation;
                    disp(['Updated recording information for ' cell_id])
                end
                
            %if the cell doesn't exist yet:
            else
                cp = struct();
                cp.cellID = cell_id;
                cp.fileLocationLocal = changePathSlashesUnix(data.(cell_id).fileLocation);
                cp.relativePathFromPipeline = changePathSlashesUnix(data.(cell_id).relativePathFromPipeline);
                cp.coordinates = data.(cell_id).coordinates;
                cp.RecordingInformation = callerObj.RecordingInformation;
                app.CellParameters = cp;
            end
            
            %set up the tags list box
            tagList = callerObj.projectInfo.tags(:, 1);
            app.AnalysisTagsListBox.Items = tagList;
            
            %get analysis tags if they exist
            preexistingTags = [];
            app.AnalysisTagsListBox.Multiselect = 1;
            if isfield(app.CellParameters, 'Tags')
                for j = 1:numel(app.CellParameters.Tags)
                    t = app.CellParameters.Tags{j};
                    tagged = 0;
                    for k = 1:numel(app.AnalysisTagsListBox.Items)
                        available = app.AnalysisTagsListBox.Items{k};
                        if strcmp(t, available)
                            preexistingTags = [preexistingTags k];
                            tagged = 1;
                            break
                        end
                    end
                    if ~tagged
                        disp(['The cell had a tag with name ' t ' but this tag does not exist for this project.' newline 'You may, close this window and add the tag in the physiology pipeline app, and then reload data for this cell'])
                    end
                end
            end
            app.AnalysisTagsListBox.Value = app.AnalysisTagsListBox.Items(preexistingTags);
            
            %Set spike detector
            app.spikeDetector = eval(app.SpikeDetectorDropDown.Value);
            
            [~,cellListFName] = fileparts(changePathSlashesUnix(callerObj.projectInfo.batchListFName));
            app.cellListPathLocal = fullfile(callerObj.localPathToSharedDir,...
                callerObj.projectInfo.relativePathFromPipeline, [cellListFName, '.mat']) ;
            
            projectName = callerObj.projectInfo.projectName;
            app.CellParameters.projects.(projectName) = callerObj.projectInfo;
            
            app.callerObj = callerObj;
            
            app.analysesPerformed = {};
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            %Use class defs for each analysis type to run an analysis.
            %There is a method in the classdef called analysis that will
            %execute the analysis function.
            analysisType = app.AnalysisTypeDropDown.Value;
            recordingType = app.RecordingTypeDropDown.Value;
            a = eval([analysisType(analysisType ~= ' '), '_PP']); %initialize analysis object - name will be without spaces
            
            a.data = app.originalData;
            a.recordingType = recordingType;
            a.recordingRig = app.callerObj.RecordingInformation.Rig;
            a.recordingDate = app.callerObj.RecordingInformation.RecordingDate;
            a.epochsSelected = str2num(app.WillAnalyzeEditField.Value);
            a.cellID = app.cellID;
            a.analysisType = analysisType;
            
            %set spike detector
            a.spikeDetector = app.spikeDetector; %this is a shallow copy of the spike detector object
            
            a.maxFiringRateWindow = app.maxFiringRateWindowSize;
            
            %run main analysis script
            tic
            disp(['Beginning Analysis For ' analysisType])
            analysisObj = a.analysis; %analysis method from the object
            disp(['Elapsed Analysis Time: ' num2str(toc) 's'])
            
            clear a %get rid of the analysis class because there are shallow copy problems w/ the spike detector
            
            processedDataStruct = analysisObj.processedData;
            if isempty(fieldnames(processedDataStruct)) %make sure the analysis was actually done. If it doesn't exist then processedDataStruct should be empty
                warndlg('Could not perform analysis as requested. Ensure that there is an analysis script associated with this stimulus and recording type')
                disp('Warning: Could not perform analysis as requested. Ensure that there is an analysis script associated with this stimulus and recording type')
                return
            end
            app.lastAnalysisObject = analysisObj;
            
            %processedDataStruct is a structure containing the single
            %analysis that you just performed.
            
            
            %add the new analaysis to CellParameters, which is a larger
            %structure containing all the analyses that have been done for
            %this cell.
            app.fillCellParameters(processedDataStruct);
            
            %update list of latest recording types run
            app.analysesPerformed{end + 1, 1} = {app.lastAnalysisType, app.lastRecordingTypeAndSuffix};
            
            %update buttons and show saved statuses
            app.SaveButton.Enable = true;
            app.SaveFigureButton.Enable = true;
            app.SaveFigureButton.FontColor = [1, 0, 0];
            app.SaveButton.FontColor = [1, 0, 0];
            app.RevertButton.Enable = true;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            currentSelectedIndices = event.Indices;
            rowToSee = currentSelectedIndices(1);
            startIndexOfRowToSee = app.UITable.Data(rowToSee, 4);
            app.EpochEditField.Value = num2str(startIndexOfRowToSee{1});
        end

        % Button pushed function: ViewDetailsButton
        function ViewDetailsButtonPushed(app, event)
            thisEpoch = app.originalData.(app.cellID).epochs(str2double(app.EpochEditField.Value));
            assignin('base', ['epoch_' app.EpochEditField.Value], thisEpoch)
            openvar(['epoch_' app.EpochEditField.Value '.meta'])
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            %first check the app state: are we selecting or deselecting?
            epochsToAnalyze = [];
            if app.selectionState == 1
                % if 0, we will now deselect all:
                for i = 1:size(app.UITable.Data, 1)
                    app.UITable.Data{i, 1} = false;
                end
                app.selectionState = 0;
                app.WillAnalyzeEditField.Value = ' ';
                return
            end

            %if the selection state was 0, then we will select all. If
            %there is a check mark checked then we will select all with the
            %same display name, otherwise we will select all epochs
            %regardless of display name
            found = 0;
            for i = 1:size(app.UITable.Data, 1)
                    if app.UITable.Data{i, 1} == true
                        found = 1;
                        displayName = app.UITable.Data{i, 2};
                        start = app.UITable.Data{i,4};
                        stop = app.UITable.Data{i, 5};
                        epochsToAnalyze = [epochsToAnalyze start:stop];
                        continue
                    end
                    if found
                        if strcmp(app.UITable.Data{i, 2}, displayName)
                            app.UITable.Data{i, 1} = true;
                            start = app.UITable.Data{i,4};
                            stop = app.UITable.Data{i, 5};
                            epochsToAnalyze = [epochsToAnalyze start:stop];
                        else
                            app.UITable.Data{i, 1} = false;
                        end
                    end
            end
            
            %if there were no checks found then select all checks
            %regardless of display name
            if ~found
                for i = 1:size(app.UITable.Data, 1)
                    app.UITable.Data{i, 1} = true;
                    start = app.UITable.Data{i,4};
                    stop = app.UITable.Data{i, 5};
                    epochsToAnalyze = [epochsToAnalyze start:stop];
                end
            end

            app.selectionState = 1;

            if ~isempty(epochsToAnalyze)
                app.WillAnalyzeEditField.Value = num2str(epochsToAnalyze);
            else
                app.WillAnalyzeEditField.Value = ' ';
            end
        end

        % Button pushed function: PlotRawDataButton
        function PlotRawDataButtonPushed(app, event)
            epochs = str2num(app.EpochEditField.Value);
            for i = 1:numel(epochs)
                epoch_i = epochs(i);
                trace = app.originalData.(app.cellID).epochs(epoch_i).epoch(:)';
                meta = app.originalData.(app.cellID).epochs(epoch_i).meta;
                try
                    totalTime = meta.preTime + meta.stimTime + meta.tailTime;
                catch
                    try
                        totalTime = meta.preTime + meta.currentStimTime + meta.tailTime;
                    catch
                        totalTime = numel(trace)/10;
                    end
                end
                
                time = linspace(0, totalTime, numel(trace));
                
                if isfield(meta, 'currentStimTime')
                    meta.stimTime = meta.currentStimTime;
                end
                
                %Executes only when you want to see the raw trace alone
                figure()
                hold on
                plot(time, trace)
                title([app.cellID ' Epoch #'  num2str(epoch_i) ' - raw trace'])
                try
                    plot([meta.preTime meta.preTime+meta.stimTime],...
                        [mean(trace) + 12*std(trace), mean(trace) + 12*std(trace)],...
                        'Color', 'k', 'LineWidth', 2)
                catch
                end
                xlabel('time')
                
                if ~app.ShowSpikeDetectionMenu.Checked
                    hold off
                    continue %stop here if you don't want to see spikes
                end
                
                %Executes only when you want to see the spike detection on the
                %raw trace
                spikeData = app.spikeDetector.detectSpikes(trace);
                spikeTimes = spikeData.sp./10; %convert to ms from sample rate by dividing by 10
                yPos = mean(trace)+ 8*std(trace) .* ones(1, numel(spikeTimes));
                scatter(spikeTimes, yPos, 'dk')
                hold off
                
                if app.CalculateMaxFiringRateForSingleEpochMenu.Checked
                    a = analysisSuperClass;
                    maxFiringRate = a.maxFiringRate(spikeTimes);
                    disp(['Maximum Firing Rate for epoch #' num2str(epoch_i) ' is ', num2str(maxFiringRate), 'hz'])
                end
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            
            epochsToAnalyze = [];
            
            %Make a guess as to what type of analysis will be run
            analysisGuess = app.UITable.Data{1, 2};
            if ismember(analysisGuess, app.AnalysisTypeDropDown.Items)
                app.AnalysisTypeDropDown.Value = analysisGuess;
            end
            
            if indices(2) == 1 %first column edits only
                for i = 1:size(app.UITable.Data, 1)
                    if app.UITable.Data{i, 1} == true
                        analysisGuess = app.UITable.Data{i, 2};
                        %Make a guess as to what type of analysis will be run
                        if ismember(analysisGuess, app.AnalysisTypeDropDown.Items)
                            app.AnalysisTypeDropDown.Value = analysisGuess;
                        end
                        
                        %update epochs to analyze field
                        start = app.UITable.Data{i,4};
                        stop = app.UITable.Data{i, 5};
                        epochsToAnalyze = [epochsToAnalyze start:stop];
                    end
                end
                if ~isempty(epochsToAnalyze)
                    app.WillAnalyzeEditField.Value = num2str(epochsToAnalyze);
                else
                    app.WillAnalyzeEditField.Value = ' ';
                end                
            end
            app.selectionState = 0; %next select button press will fill checks
        end

        % Button pushed function: RevertButton
        function RevertButtonPushed(app, event)
            %resets cell parameters to the last saved version... you can
            %never revert further back than the last time you saved.
            cp_path = fullfile(app.originalData.(app.cellID).fileLocation, app.cellID, [app.cellID, '_Analysis.mat']);
            if exist(cp_path) == 2
                load(cp_path); %loads CellParameters struct
                app.CellParameters = CellParameters;
            else
                cp = struct();
                cp.cellID = app.cellID;
                cp.fileLocationLocal = app.originalData.(cell_id).fileLocation;
                cp.relativePathFromPipeline = app.originalData.(cell_id).relativePathFromPipeline;
                cp.coordinates = app.originalData.(app.cellID).coordinates;
                cp.RecordingInformation = app.callerObj.RecordingInformation;
                projectName = app.callerObj.projectInfo.projectName;
                cp.projects.(projectName) = app.callerObj.projectInfo;
                app.CellParameters = cp;
            end
            app.SaveButton.Enable = false; %show saved
            app.SaveFigureButton.Enable = false;
            app.RevertButton.Enable = false;
            
            %reset analysesPerformed, which helps update cellList with the
            %latest analyses run since last save.
            app.analysesPerformed = {};
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %find the proper save location
            saveLocation = fullfile(app.CellParameters.fileLocationLocal, app.cellID); %This is the normal value for saveLocation (i.e. the default)
            
            %Add analysis Tags and Physiology Cell Type
            app.CellParameters.Tags = app.AnalysisTagsListBox.Value;
            
            %check if the subfolder exists for this cell, if not create it
            if exist(saveLocation)~=7
                mkdir(saveLocation);
            end
            
            fname = fullfile(saveLocation, [app.cellID '_Analysis.mat']);
            CellParameters = app.CellParameters; %change from app property to stand alone variable for save function
            save(fname, 'CellParameters') %save a .mat file containing structure "CellParameters"
            
            disp('Success: Saved data')
            
            if app.UpdateCellListMenu.Checked
                app.updateCellList(); %update the cellList to note a new save for this cell.
            else
                disp('Cell list batch updates are turned off...')
            end
            
            app.SaveButton.FontColor = [.47,0.67,0.19]; %button changes to green
            
            app.RevertButton.Enable = false; %take away revert button because reverting will just bring back the copy that was last saved... i.e. this one
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            %find the proper save location
            saveLocation = fullfile(app.CellParameters.fileLocationLocal, app.cellID); %This is the normal value for saveLocation (i.e. the default)
            
            %check if the subfolder exists for this cell, if not create it.
            if exist(saveLocation)~=7
                mkdir(saveLocation);
            end
            
            %save the figure to the correct folder by referencing its
            %handle (which has been temporarily saved as a property)
            for i = 1:numel(app.lastAnalysisObject.analysisFigures)
                fig = app.lastAnalysisObject.analysisFigures(i);
                extension = app.lastAnalysisObject.analysisFigureExtensions{i};
                fname = fullfile(saveLocation, [app.lastAnalysisType, '_', app.lastRecordingTypeAndSuffix, extension, '.svg']);
                saveas(fig, fname);
            end
            
            app.SaveFigureButton.FontColor = [.47,0.67,0.19]; %button changes to green;
            disp('Success: Saved Figure')
        end

        % Callback function
        function TraceOnlyMenuSelected(app, event)
            app.TraceOnlyMenu.Checked = 1;
            app.ShowSpikeDetectionMenu.Checked = 0;
        end

        % Menu selected function: ShowSpikeDetectionMenu
        function ShowSpikeDetectionMenuSelected(app, event)
            app.ShowSpikeDetectionMenu.Checked = abs(1 - app.ShowSpikeDetectionMenu.Checked); %clever way of switching back and forth between 0 and 1
            if app.ShowSpikeDetectionMenu.Checked
                app.spikeSwitch.Value = 'On';
            else
                app.spikeSwitch.Value = 'Off';
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            app.SaveButtonPushed;
            disp('Save Successful at '); disp(datetime)
        end

        % Menu selected function: UpdateCellListMenu
        function UpdateCellListMenuSelected(app, event)
            app.UpdateCellListMenu.Checked = abs(1 - app.UpdateCellListMenu.Checked); %clever way of switching back and forth between 0 and 1
        end

        % Menu selected function: SetFiringRateWindowMenu
        function SetFiringRateWindowMenuSelected(app, event)
            userInput = inputdlg('Enter sliding window value for max firing rate (in ms):', 'Maximum Firing Rate Window', [1 40], {'50'});
            val = str2num(userInput{1});
            app.maxFiringRateWindowSize = val;
        end

        % Menu selected function: CalculateMaxFiringRateForSingleEpochMenu
        function CalculateMaxFiringRateForSingleEpochMenuSelected(app, event)
            app.CalculateMaxFiringRateForSingleEpochMenu.Checked = abs(1 - app.CalculateMaxFiringRateForSingleEpochMenu.Checked); %clever way of switching back and forth between 0 and 1
        end

        % Menu selected function: AddPairMenu
        function AddPairMenuSelected(app, event)
            pair = uigetfile('*.mat', 'Select paired cells', 'MultiSelect', 'off');
            if isfield(app.CellParameters, 'PairedCells')
                app.CellParameters.PairedCells(end+1) = {pair(1:strfind(pair, '_ClarinetExport')-1)};
            else
                app.CellParameters.PairedCells = {pair(1:strfind(pair, '_ClarinetExport')-1)};
            end
            msgbox('View command window for pairs')
            disp(app.CellParameters.PairedCells)
        end

        % Menu selected function: AddOtherAmpMenu
        function AddOtherAmpMenuSelected(app, event)
             partner = uigetfile('*.mat', 'Select Clarinet Export of Cell that Was Recorded Simultaneously', 'MultiSelect', 'off');
             partnerName = partner(1:strfind(partner, '_ClarinetExport')-1);
             app.CellParameters.OtherAmp = {partnerName};
             if strcmp(partnerName, app.cellID)
                 app.CellParameters = rmfield(app.CellParameters, 'OtherAmp');
                 msgbox('Removed Other Amp')
                 return
             end
            msgbox(['You added ' partnerName, ' as a simultaneously recorded cell'])
        end

        % Button pushed function: FillButton
        function FillButtonPushed(app, event)
            app.EpochEditField.Value = app.WillAnalyzeEditField.Value;
        end

        % Menu selected function: CesiumMenu
        function CesiumMenuSelected(app, event)
            app.CesiumMenu.Checked = abs(1 - app.CesiumMenu.Checked); %clever way of switching back and forth between 0 and 1
            app.PotassiumMenu.Checked = 0;
            app.HEPEsMenu.Checked = 0;
            app.SolutionUnspecifiedLabel.Visible = 1;
            app.SolutionUnspecifiedLabel.Text = 'Solution: Cesium';
        end

        % Menu selected function: PotassiumMenu
        function PotassiumMenuSelected(app, event)
            app.PotassiumMenu.Checked = abs(1 - app.PotassiumMenu.Checked); %clever way of switching back and forth between 0 and 1
            app.CesiumMenu.Checked = 0;
            app.HEPEsMenu.Checked = 0;
            app.SolutionUnspecifiedLabel.Visible = 1;
            app.SolutionUnspecifiedLabel.Text = 'Solution: Potassium';
        end

        % Menu selected function: HEPEsMenu
        function HEPEsMenuSelected(app, event)
            app.HEPEsMenu.Checked = abs(1 - app.HEPEsMenu.Checked); %clever way of switching back and forth between 0 and 1
            app.CesiumMenu.Checked = 0;
            app.PotassiumMenu.Checked = 0;
            app.SolutionUnspecifiedLabel.Visible = 1;
            app.SolutionUnspecifiedLabel.Text = 'Solution: HEPEs';
        end

        % Value changed function: RecordingTypeDropDown
        function RecordingTypeDropDownValueChanged(app, event)
            value = app.RecordingTypeDropDown.Value;
            if contains(value, '(Intracellular)') && app.HEPEsMenu.Checked
                warndlg('Pipette Solution is set to HEPEs. Change in Analysis/Pipette Solution menu')
            end
            
            if contains(value, 'Extracellular') && (app.CesiumMenu.Checked || app.PotassiumMenu.Checked)
                warndlg('Pipette solution is set to Cesium or Potassium. Change in Analysis/Pipette Solution menu')
            end
            
            if contains(value, 'Cesium') && ~app.CesiumMenu.Checked
                app.CesiumMenuSelected;
                warndlg('Automatically changed pipette solution to Cesium. Manually change back in Analysis/Pipette Soultion menu')
            end
            
            if (contains(value, 'Potassium') || contains(value, 'K+')) && ~app.PotassiumMenu.Checked
                app.PotassiumMenuSelected;
                warndlg('Automatically changed pipette solution to Potasium. Manually change back in Analysis/Pipette Soultion menu')
            end
        end

        % Menu selected function: OpenNotesMenu
        function OpenNotesMenuSelected(app, event)
            %Open the notes file for this recording
            fpath = app.CellParameters.fileLocationLocal;
            found = 0;
            listing = dir(fpath);
            for i = 1:numel(listing)
                file_i = listing(i).name;
                if contains(file_i, '.txt')
                    fname = file_i;
                    found = 1;
                    break
                end
            end
            notesFname = fullfile(fpath, fname);
            if found
                try
                    system(['"' notesFname '"&']);
                catch
                    errordlg('Could not locate or open the notes file. Open manually')
                end
            else
                errordlg('Could not locate or open the notes file. Open manually')
            end
        end

        % Button pushed function: IDButton
        function IDButtonPushed(app, event)
            % Determine the stimulus ID of the selected epoch
            epochNumber = str2double(app.EpochEditField.Value);
            stimulusIDStructPath = fullfile(app.callerObj.localPathToSharedDir, 'appData\stimulusIDs\stimulusIDs_PP.mat');
            load(stimulusIDStructPath); %loads stimulusIDStruct
            metaStruct = app.originalData.(app.cellID).epochs(epochNumber).meta;
            [~, stimulusID] = findStimulusID_PP(stimulusIDs, metaStruct);
            app.SuffixEditField.Value = ['_', stimulusID];
        end

        % Button pushed function: SetPropsButton
        function SetPropsButtonPushed(app, event)
            props = properties(app.spikeDetector);
            figWidth = 240;
            figHeight = 30*numel(props)+60+30;
            fig = uifigure('Position', [500, 200, figWidth, figHeight]);
            lbl = uilabel(fig); lbl.Text = 'Spike Detector Properties'; lbl.FontSize = 14; lbl.Position = [40, figHeight-50, 200, 50];
            fieldHandleArray = {};
            for i = 1:numel(props)
                prop_i = props{i};
                lbl = uilabel(fig);
                lbl.Text = prop_i;
                height_i = figHeight - 60 - 30*(i-1);
                lbl.Position = [10, height_i, 100, 25];
                fld = uieditfield(fig, 'numeric');
                fld.Position = [140, height_i, 80, 25];
                fld.Value = app.spikeDetector.(prop_i);
                fieldHandleArray{end+1, 1} = prop_i;
                fieldHandleArray{end, 2} = fld;
                if strcmp(prop_i, 'SR')
                    fld.Enable = 0; %Edit the spikeDetectors superclass directly to change the sample rate
                end
            end
            btn = uibutton(fig, 'Position', [figWidth/2, 10, 100, 30]);
            btn.Text = 'Submit Changes';
            btn.BackgroundColor = [0.3, 0.6, 0.3];
            btn.ButtonPushedFcn = @(btn, event) continueBtnPushed(btn, fig, fieldHandleArray, app);
            app.spikeDetectionFigHandle = fig;
            
            function continueBtnPushed(~, fig, fieldHandleArray, app)
                for j = 1:size(fieldHandleArray, 1)
                    fieldHandle_j = fieldHandleArray{j, 2};
                    value_j = fieldHandle_j.Value;
                    field_j = fieldHandleArray{j, 1};
                    app.spikeDetector.(field_j) = value_j;
                end
                disp('Spike detector properties were successfully updated')
            end
        end

        % Value changed function: SpikeDetectorDropDown
        function SpikeDetectorDropDownValueChanged(app, event)
            value = app.SpikeDetectorDropDown.Value;
            clear app.spikeDetector
            app.spikeDetector = eval(value);
            if ishandle(app.spikeDetectionFigHandle)
                previousWindowPosition = app.spikeDetectionFigHandle.Position;
                close(app.spikeDetectionFigHandle)
                clear app.spikeDetectionFigHandle
                app.SetPropsButtonPushed();
                app.spikeDetectionFigHandle.Position(1) = previousWindowPosition(1);
                app.spikeDetectionFigHandle.Position(2) = previousWindowPosition(2);
            end
        end

        % Value changed function: spikeSwitch
        function spikeSwitchValueChanged(app, event)
            value = app.spikeSwitch.Value;
            if strcmp(value, 'Off')
                app.ShowSpikeDetectionMenu.Checked = 0;
            elseif strcmp(value, 'On')
                app.ShowSpikeDetectionMenu.Checked = 1;
            end
        end

        % Close request function: dataViewerUI
        function dataViewerUICloseRequest(app, event)
            %close the spike detector params window if it is still open (so
            %that it doesn't get confused with other apps'
            if ishandle(app.spikeDetectionFigHandle)
                close(app.spikeDetectionFigHandle)
                disp('Closed the spike detection "set properties" window. Open a new one for the next cell if needed')
            end
            delete(app)
        end

        % Menu selected function: BlueMenu, GreenMenu, OrangeMenu, 
        % ...and 3 other components
        function AddFlag(app, event)
            %take the values in the will analyze box and flag those epochs
            %with the given color

            %figure out which flag button was bushed
            flagPushed = event.Source.Text;
            if isfield(app.CellParameters, 'Flags') && isfield(app.CellParameters.Flags, (flagPushed))
                answer = questdlg(['You have already created a ' flagPushed ' flag for this cell. Would you like to overwrite it?'],...
                    'Flag Overwrite Warning', 'Yes, Overwrite', 'No, Cancel', 'No, Cancel');
                if strcmp(answer, 'No, Cancel')
                    disp('Flag add successfully aborted')
                    return
                end
            end

            %get the values from the will analyze box
            toAdd = str2num(app.WillAnalyzeEditField.Value);
            if isempty(toAdd)
                warndlg('No values entered in the Will Analyze field. Aborting function. Add epoch numbers before flagging')
                return
            end
            
            %allow user to add a description
            description = inputdlg('Add a description for your flag', 'Flag Description', [6, 60], "Must enter description");
             while isempty(description{1})
                        disp('!!Cannot enter an empty description. Try again')
                        description = inputdlg('Add a description for your flag', 'Flag Description', [6, 60], "Must enter description");
             end
            
            
            app.CellParameters.Flags.(flagPushed).epochs = toAdd;
            app.CellParameters.Flags.(flagPushed).description = description;

            disp([flagPushed ' Flag' newline 'Epochs: ' num2str(toAdd) newline 'Description: ' description{1} newline])
        end

        % Menu selected function: PrintFlagsMenu
        function PrintFlagsMenuSelected(app, event)
            if isfield(app.CellParameters, 'Flags')
                flags = fieldnames(app.CellParameters.Flags);
                for i = 1:numel(flags)
                    epochs = app.CellParameters.Flags.(flags{i}).epochs;
                    description = app.CellParameters.Flags.(flags{i}).description;
                    flagName = flags{i};
                    disp([flagName ' Flag' newline 'Epochs: ' num2str(epochs) newline 'Description: ' description{1} newline])
                end
            else
                disp('No flags have been added for this cell yet.')
            end
        end

        % Menu selected function: EditFlagsMenu
        function EditFlagsMenuSelected(app, event)
            if ~isfield(app.CellParameters, 'Flags')
                warndlg('No flags have been added for this cell yet. You must add a flag before editing')
                return
            end

            existingFlags = fieldnames(app.CellParameters.Flags);
            [indx, tf] = listdlg('ListString', existingFlags, 'PromptString', 'Select the flag to edit');
            if ~tf
                disp('No selection made, aborting flag edit.')
            end
            
            epochs = app.CellParameters.Flags.(existingFlags{indx}).epochs;
            description = app.CellParameters.Flags.(existingFlags{indx}).description;
            flagNameToEdit = existingFlags{indx};
            
            disp(['Currently editing the ' flagNameToEdit 'Flag. This flag currently contains the following information,'...
                newline 'Epochs: ' num2str(epochs) newline 'Description: ' description{1} newline])
            answer = uiconfirm(app.dataViewerUI,['Would you like to edit the epochs, edit the description, or delete the ' flagNameToEdit ' Flag from this cell (see the command window for a description of its current properties)?'...
                ' Selecting epochs will use the current values in the Will Analyze box.'], 'Edit Selection', ...
                "Options",["Epochs","Description", "Delete Flag", "Cancel"], "DefaultOption",4);
            drawnow();
            figure(app.dataViewerUI)
            
            switch answer
                case 'Cancel'
                    disp('Aborting flag edit. No edits were made.')
                    return
                case 'Epochs'
                    newEpochs = str2num(app.WillAnalyzeEditField.Value);
                    if isempty(newEpochs)
                        warndlg('The Will Analyze box appears empty. Add epochs there before editing. If you would like to remove all epochs, delete the flag instead.')
                        return
                    end
                    app.CellParameters.Flags.(existingFlags{indx}).epochs = newEpochs;
                case 'Description'
                    oldDescription = app.CellParameters.Flags.(existingFlags{indx}).description;
                    newDescription = inputdlg('Add a description for your flag', 'Flag Description', [6, 60], oldDescription);
                    while isempty(newDescription{1})
                        disp('!!Cannot enter an empty description. Try again')
                        newDescription = inputdlg('Add a description for your flag', 'Flag Description', [6, 60], oldDescription);
                    end
                    app.CellParameters.Flags.(existingFlags{indx}).description = newDescription;
                case 'Delete Flag'
                    app.CellParameters.Flags = rmfield(app.CellParameters.Flags, existingFlags{indx});
                    disp(['**Deleted the ' existingFlags{indx} ' flag.**'])
            end
            
            disp('Edits complete, printing all flags to the command window. Remember to save your changes')
            app.PrintFlagsMenuSelected();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.dataViewerUI = uifigure('Visible', 'off');
            app.dataViewerUI.Name = 'UI Figure';
            app.dataViewerUI.CloseRequestFcn = createCallbackFcn(app, @dataViewerUICloseRequest, true);
            app.FileMenu = uimenu(app.dataViewerUI);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.UpdateCellListMenu = uimenu(app.FileMenu);
            app.UpdateCellListMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateCellListMenuSelected, true);
            app.UpdateCellListMenu.Tooltip = {'If checked, saving data to Cell List'};
            app.UpdateCellListMenu.Checked = 'on';
            app.UpdateCellListMenu.Text = 'Update Cell List';
            app.OpenNotesMenu = uimenu(app.FileMenu);
            app.OpenNotesMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenNotesMenuSelected, true);
            app.OpenNotesMenu.Text = 'Open Notes';
            app.AnalysisMenu = uimenu(app.dataViewerUI);
            app.AnalysisMenu.Text = 'Analysis';
            app.PipetteSolutionMenu = uimenu(app.AnalysisMenu);
            app.PipetteSolutionMenu.Text = 'Pipette Solution';
            app.CesiumMenu = uimenu(app.PipetteSolutionMenu);
            app.CesiumMenu.MenuSelectedFcn = createCallbackFcn(app, @CesiumMenuSelected, true);
            app.CesiumMenu.Text = 'Cesium';
            app.PotassiumMenu = uimenu(app.PipetteSolutionMenu);
            app.PotassiumMenu.MenuSelectedFcn = createCallbackFcn(app, @PotassiumMenuSelected, true);
            app.PotassiumMenu.Text = 'Potassium';
            app.HEPEsMenu = uimenu(app.PipetteSolutionMenu);
            app.HEPEsMenu.MenuSelectedFcn = createCallbackFcn(app, @HEPEsMenuSelected, true);
            app.HEPEsMenu.Text = 'HEPEs';
            app.AddPairMenu = uimenu(app.AnalysisMenu);
            app.AddPairMenu.MenuSelectedFcn = createCallbackFcn(app, @AddPairMenuSelected, true);
            app.AddPairMenu.Tooltip = {'List another cell as a pair with this current cell. To do so, select the other cell''s _clarinetExport .mat file'};
            app.AddPairMenu.Text = 'Add Pair';
            app.AddOtherAmpMenu = uimenu(app.AnalysisMenu);
            app.AddOtherAmpMenu.MenuSelectedFcn = createCallbackFcn(app, @AddOtherAmpMenuSelected, true);
            app.AddOtherAmpMenu.Tooltip = {'For paired (2 amplifier) recordings'};
            app.AddOtherAmpMenu.Text = 'Add Other Amp';
            app.SetFiringRateWindowMenu = uimenu(app.AnalysisMenu);
            app.SetFiringRateWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @SetFiringRateWindowMenuSelected, true);
            app.SetFiringRateWindowMenu.Text = 'Set Firing Rate Window';
            app.FlagEpochsMenu = uimenu(app.AnalysisMenu);
            app.FlagEpochsMenu.Text = 'Flag Epochs';
            app.RedMenu = uimenu(app.FlagEpochsMenu);
            app.RedMenu.MenuSelectedFcn = createCallbackFcn(app, @AddFlag, true);
            app.RedMenu.Text = 'Red';
            app.OrangeMenu = uimenu(app.FlagEpochsMenu);
            app.OrangeMenu.MenuSelectedFcn = createCallbackFcn(app, @AddFlag, true);
            app.OrangeMenu.Text = 'Orange';
            app.YellowMenu = uimenu(app.FlagEpochsMenu);
            app.YellowMenu.MenuSelectedFcn = createCallbackFcn(app, @AddFlag, true);
            app.YellowMenu.Text = 'Yellow';
            app.GreenMenu = uimenu(app.FlagEpochsMenu);
            app.GreenMenu.MenuSelectedFcn = createCallbackFcn(app, @AddFlag, true);
            app.GreenMenu.Text = 'Green';
            app.BlueMenu = uimenu(app.FlagEpochsMenu);
            app.BlueMenu.MenuSelectedFcn = createCallbackFcn(app, @AddFlag, true);
            app.BlueMenu.Text = 'Blue';
            app.PurpleMenu = uimenu(app.FlagEpochsMenu);
            app.PurpleMenu.MenuSelectedFcn = createCallbackFcn(app, @AddFlag, true);
            app.PurpleMenu.Text = 'Purple';
            app.PrintFlagsMenu = uimenu(app.FlagEpochsMenu);
            app.PrintFlagsMenu.MenuSelectedFcn = createCallbackFcn(app, @PrintFlagsMenuSelected, true);
            app.PrintFlagsMenu.Separator = 'on';
            app.PrintFlagsMenu.Text = 'Print Flags';
            app.EditFlagsMenu = uimenu(app.FlagEpochsMenu);
            app.EditFlagsMenu.MenuSelectedFcn = createCallbackFcn(app, @EditFlagsMenuSelected, true);
            app.EditFlagsMenu.Text = 'Edit Flags';
            app.SaveFigureButton = uibutton(app.dataViewerUI, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Enable = 'off';
            app.SaveFigureButton.Tooltip = {'Save the figure from the last analysis (.svg)'};
            app.SaveFigureButton.Text = 'Save Figure';
            app.SaveButton = uibutton(app.dataViewerUI, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Tooltip = {'Save the cell parameter file that you have generated'};
            app.SaveButton.Text = 'Save';
            app.RevertButton = uibutton(app.dataViewerUI, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @RevertButtonPushed, true);
            app.RevertButton.Enable = 'off';
            app.RevertButton.Tooltip = {'Revert to last saved data. If nothing has been saved for this cell, you will revert back to a blank file.'};
            app.RevertButton.Text = 'Revert';
            app.RunAnalysisButton = uibutton(app.dataViewerUI, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Tooltip = {'Run the analysis.This may take a while.'};
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.SuffixEditFieldLabel = uilabel(app.dataViewerUI);
            app.SuffixEditFieldLabel.Text = 'Suffix';
            app.SuffixEditField = uieditfield(app.dataViewerUI, 'text');
            app.SuffixEditField.Tooltip = {'Add the ID tag here'};
            app.CellIDDataViewerLabel = uilabel(app.dataViewerUI);
            app.CellIDDataViewerLabel.Text = 'CellID - Data Viewer';
            app.UITable = uitable(app.dataViewerUI);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AnalysisTagsListBoxLabel = uilabel(app.dataViewerUI);
            app.AnalysisTagsListBoxLabel.Text = 'Analysis Tags';
            app.AnalysisTagsListBox = uilistbox(app.dataViewerUI);
            app.AnalysisTagsListBox.Items = {};
            app.AnalysisTagsListBox.Multiselect = 'on';
            app.AnalysisTagsListBox.Tooltip = {'Set analysis tags for this cell. Tags are saved once for the cell, not per analysis.You may select multiple tags by holding down the cntrl/cmnd button.'};
            app.AnalysisTagsListBox.Value = {};
            app.ViewEpochsLabel = uilabel(app.dataViewerUI);
            app.ViewEpochsLabel.Text = 'View Epochs';
            app.EpochEditFieldLabel = uilabel(app.dataViewerUI);
            app.EpochEditFieldLabel.Text = 'Epoch #';
            app.EpochEditField = uieditfield(app.dataViewerUI, 'text');
            app.EpochEditField.Tooltip = {'Epoch numbers to be inspected'};
            app.ViewDetailsButton = uibutton(app.dataViewerUI, 'push');
            app.ViewDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewDetailsButtonPushed, true);
            app.ViewDetailsButton.Text = 'View Details';
            app.PlotRawDataButton = uibutton(app.dataViewerUI, 'push');
            app.PlotRawDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRawDataButtonPushed, true);
            app.PlotRawDataButton.Tooltip = {'Right click to turn on spike detection'};
            app.PlotRawDataButton.Text = 'Plot Raw Data';
            app.RecordingTypeDropDownLabel = uilabel(app.dataViewerUI);
            app.RecordingTypeDropDownLabel.Text = 'Recording Type';
            app.RecordingTypeDropDown = uidropdown(app.dataViewerUI);
            app.RecordingTypeDropDown.Items = {'Extracellular', 'Excitation (Intracellular)', 'Inhibition (Intracellular)', 'Current Clamp (K+ Spikes)', 'Current Clamp (Cesium)'};
            app.RecordingTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @RecordingTypeDropDownValueChanged, true);
            app.RecordingTypeDropDown.Tooltip = {'The type of recording that was made'};
            app.RecordingTypeDropDown.Value = 'Extracellular';
            app.AnalysisTypeDropDownLabel = uilabel(app.dataViewerUI);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisTypeDropDown = uidropdown(app.dataViewerUI);
            app.AnalysisTypeDropDown.Items = {'Flash', 'Bar Noise', 'Led Pulse Family', 'Mini Post Synaptic Currents', 'Moving Bar', 'Moving Bar Intensities', 'Moving Bar Speed Variable Stim Time', 'Moving Bar Speed Variable Width', 'Moving Grating Direction', 'Moving Grating Speed', 'Moving Grating Spatial Frequency', 'Oscillating Grating', 'Pulse Family', 'Ramp'};
            app.AnalysisTypeDropDown.Tooltip = {'Which analyis file to use (usually the same name as the stimulus)'};
            app.AnalysisTypeDropDown.Value = 'Flash';
            app.WillAnalyzeEditFieldLabel = uilabel(app.dataViewerUI);
            app.WillAnalyzeEditFieldLabel.Text = 'Will Analyze';
            app.WillAnalyzeEditField = uieditfield(app.dataViewerUI, 'text');
            app.WillAnalyzeEditField.Tooltip = {'Epoch numbers to include in the next analysis'};
            app.DefineAnalysisLabel = uilabel(app.dataViewerUI);
            app.DefineAnalysisLabel.Text = 'Define Analysis';
            app.AVOIDCheckBox = uicheckbox(app.dataViewerUI);
            app.AVOIDCheckBox.Tooltip = {'Check this box if you would like to add an AVOID tag to this analysis (not the entire cell)'};
            app.AVOIDCheckBox.Text = 'AVOID';
            app.FillButton = uibutton(app.dataViewerUI, 'push');
            app.FillButton.ButtonPushedFcn = createCallbackFcn(app, @FillButtonPushed, true);
            app.FillButton.Tooltip = {'Fill the box above with what is listed in the "Will Analyze" box'};
            app.FillButton.Text = 'Fill';
            app.SolutionUnspecifiedLabel = uilabel(app.dataViewerUI);
            app.SolutionUnspecifiedLabel.Tooltip = {'Intracellular solution used for this recording. Change in the "analysis" menu bar.'};
            app.SolutionUnspecifiedLabel.Text = 'Solution: Unspecified';
            app.IDButton = uibutton(app.dataViewerUI, 'push');
            app.IDButton.ButtonPushedFcn = createCallbackFcn(app, @IDButtonPushed, true);
            app.IDButton.Tooltip = {'Look for a preexisting ID tag'};
            app.IDButton.Text = {'ID'; ''};
            app.AnalyzeLabel = uilabel(app.dataViewerUI);
            app.AnalyzeLabel.Text = 'Analyze';
            app.SetPropsButton = uibutton(app.dataViewerUI, 'push');
            app.SetPropsButton.ButtonPushedFcn = createCallbackFcn(app, @SetPropsButtonPushed, true);
            app.SetPropsButton.Text = 'Set Props';
            app.SpikeDetectionLabel = uilabel(app.dataViewerUI);
            app.SpikeDetectionLabel.Text = 'Spike Detection';
            app.SpikeDetectorDropDown = uidropdown(app.dataViewerUI);
            app.SpikeDetectorDropDown.Items = {'localSpikeDetector_SH_PP', 'simpleSpikeDetector_SH_PP', 'trendsSpikeDetector_SH_PP', 'spikeDetector_SK_PP', 'SWTTEO_JF_ND'};
            app.SpikeDetectorDropDown.ValueChangedFcn = createCallbackFcn(app, @SpikeDetectorDropDownValueChanged, true);
            app.SpikeDetectorDropDown.Value = 'localSpikeDetector_SH_PP';
            app.spikeSwitch = uiswitch(app.dataViewerUI, 'slider');
            app.spikeSwitch.ValueChangedFcn = createCallbackFcn(app, @spikeSwitchValueChanged, true);
            app.SelectButton = uibutton(app.dataViewerUI, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Tooltip = {'Select or Deselect all epoch groups. If an epoch group is checked, selection will occur first for other groups with the same display name.'};
            app.SelectButton.Text = 'Select';
            app.ContextMenu = uicontextmenu(app.dataViewerUI);
            app.PlotOptionsMenu = uimenu(app.ContextMenu);
            app.PlotOptionsMenu.Text = 'Plot Options';
            app.ShowSpikeDetectionMenu = uimenu(app.PlotOptionsMenu);
            app.ShowSpikeDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowSpikeDetectionMenuSelected, true);
            app.ShowSpikeDetectionMenu.Tooltip = {'Check to show spike detection in raw trace'};
            app.ShowSpikeDetectionMenu.Text = 'Show Spike Detection';
            app.CalculateMaxFiringRateForSingleEpochMenu = uimenu(app.PlotOptionsMenu);
            app.CalculateMaxFiringRateForSingleEpochMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateMaxFiringRateForSingleEpochMenuSelected, true);
            app.CalculateMaxFiringRateForSingleEpochMenu.Text = 'Calculate Max Firing Rate';
            % Assign app.ContextMenu
            app.PlotRawDataButton.ContextMenu = app.ContextMenu;
            app.ConditionsContextMenu = uicontextmenu(app.dataViewerUI);
            app.ClearMenu = uimenu(app.ConditionsContextMenu);
            app.ClearMenu.Text = 'Clear';
            % Show the figure after all components are created
            app.dataViewerUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dataViewer_PP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.dataViewerUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.dataViewerUI)
        end
    end
end