classdef MembranePotentialExtraction_CurrentClamp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VmCutoffEditFieldLabel          matlab.ui.control.Label
        VmCutoffEditField               matlab.ui.control.NumericEditField
        FirstDerivativeCutoffEditFieldLabel  matlab.ui.control.Label
        FirstDerivativeCutoffEditField  matlab.ui.control.NumericEditField
        SecondDerivativeCutoffEditFieldLabel  matlab.ui.control.Label
        SecondDerivativeCutoffEditField  matlab.ui.control.NumericEditField
        ParametersLabel                 matlab.ui.control.Label
        MovmeanWindowEditFieldLabel     matlab.ui.control.Label
        MovmeanWindowEditField          matlab.ui.control.NumericEditField
        PlotExampleButton               matlab.ui.control.Button
        PlotAllButton                   matlab.ui.control.Button
        AnalyzeLabel                    matlab.ui.control.Label
        ExampleTraceNumberEditFieldLabel  matlab.ui.control.Label
        ExampleTraceNumberEditField     matlab.ui.control.NumericEditField
        RunAnalysisButton               matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        ofLabel                         matlab.ui.control.Label
        MainPlot                        matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        PlotSpecificOrientation         matlab.ui.container.Menu
        ResetMenu                       matlab.ui.container.Menu
    end

    
    properties (Access = private)
        traces % matrix of all the loaded traces
    end
    
    properties (Access = public)
        readyToProceed = 0; %Flag that changes to 1 when the use has decided that the app is done
        extractedTraces %matrix of all the processed traces w/o spikes
        extractionParams %structure containing app settings to get to the trace without spikes
    end
    
    methods (Access = private)
        
        function results = removeSpikes(app, trace)
            %The algorithm that actually does the spike removal
            
            %grab cuttoff values from the app edit fields
            mVCutOff = app.VmCutoffEditField.Value;
            FirstDCutoff = app.FirstDerivativeCutoffEditField.Value*1e-3;
            SecondDCutoff = app.SecondDerivativeCutoffEditField.Value*1e-3;
            
            %smooth the trace using a moving mean
            smoothedTrace = movmean(trace, app.MovmeanWindowEditField.Value);
            deriv = diff(smoothedTrace);
            doubleDeriv = diff(deriv);
            
            %go through the trace taking only the points that meet the
            %criteria specified by the cutoff values
            goodPoints = ones(size(doubleDeriv));
            for i = 1:numel(doubleDeriv)
                derivIndex = i + 1;
                traceIndex = i + 2;
                
                tracePoint = smoothedTrace(traceIndex);
                derivPoint = deriv(derivIndex);
                doubleDerivPoint = doubleDeriv(i);
                
                if tracePoint > mVCutOff || abs(derivPoint) > FirstDCutoff...
                        || abs(doubleDerivPoint) > SecondDCutoff
                goodPoints(i) = 0;
                end
            end
            
            %make everything the same length as the double derivative
            traceForDouble = smoothedTrace(3:end);
            derivForDouble = deriv(2:end);
            
            %convert to logical for indexing
            goodPoints = logical(goodPoints);
            
            %find the good points (i.e. those that aren't part of a spike)
            %and reconstruct the trace using just those
            goodXVals = [1, 2];
            for i = 1:numel(goodPoints)
                if goodPoints(i)
                    goodXVals = [goodXVals, i + 2];
                end
            end
            goodYVals = trace(goodXVals);
            
            %interpolate the missing points
            results.interpolatedTrace = interp1(goodXVals, goodYVals, 1:numel(smoothedTrace));
            results.fractionKept = sum(goodPoints)/numel(goodPoints);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Epochs)
            %Epochs is an NxM matrix, where N is the number of observations per epoch and
            %M is the number of epochs. Each column holds the trace that comes off of the
            %amplifier for each epoch
            app.traces = Epochs;
            app.ofLabel.Text = ['of ' num2str(size(Epochs, 2))];
        end

        % Button pushed function: PlotExampleButton
        function PlotExampleButtonPushed(app, event)
            requestedTrace = app.ExampleTraceNumberEditField.Value;
            if requestedTrace > size(app.traces, 2)
                disp(['Cannot execute. There are only ' num2str(size(app.traces, 2))...
                    ' traces in the data, but you asked for trace ', num2str(requestedTrace)]);
                return
            end
            trace = app.traces(:, requestedTrace);
            results = app.removeSpikes(trace);
            
            cla(app.MainPlot);
            hold(app.MainPlot, 'on')
            plot(app.MainPlot, trace, 'k')
            plot(app.MainPlot, results.interpolatedTrace,'r')
            hold(app.MainPlot, 'off')
        end

        % Button pushed function: PlotAllButton
        function PlotAllButtonPushed(app, event)
            for i = 1:size(app.traces, 2)
                results = app.removeSpikes(app.traces(:, i));
                figure
                title(['Trace number ', num2str(i)])
                hold on
                plot(app.traces(:, i), 'k')
                plot(results.interpolatedTrace, 'r')
                xlabel('Observation')
                ylabel('mV')
                %                 ylim([-40, 10])
                %                 xlim([1000, 4500]*10)
                hold off
            end
            
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            disp('Vm Extraction set...')
            s = tic;
            app.extractedTraces = zeros(size(app.traces));
            for i = 1:size(app.traces, 2)
                results = app.removeSpikes(app.traces(:, i));
                app.extractedTraces(:, i) = results.interpolatedTrace;
            end
            disp('Finished Vm Extraction!')
            toc(s)
            params = struct();
            params.movmeanWindow = app.MovmeanWindowEditField.Value;
            params.mVCutOff = app.VmCutoffEditField.Value;
            params.FirstDCutoff = app.FirstDerivativeCutoffEditField.Value;
            params.SecondDCutoff = app.SecondDerivativeCutoffEditField.Value;
            app.extractionParams = params;
            
            app.readyToProceed = 1;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            currentIndex = app.ExampleTraceNumberEditField.Value;
            maxIndex = size(app.traces, 2);
            nextIndex = currentIndex + 1;
            if nextIndex>maxIndex
                nextIndex = currentIndex;
            end
            app.ExampleTraceNumberEditField.Value = nextIndex;
            app.PlotExampleButtonPushed();
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            currentIndex = app.ExampleTraceNumberEditField.Value;
            nextIndex = currentIndex -1;
            if nextIndex<1
                nextIndex = currentIndex;
            end
            app.ExampleTraceNumberEditField.Value = nextIndex;
            app.PlotExampleButtonPushed();
        end

        % Value changed function: ExampleTraceNumberEditField
        function ExampleTraceNumberEditFieldValueChanged(app, event)
            value = app.ExampleTraceNumberEditField.Value;
            app.PlotExampleButtonPushed();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VmCutoffEditFieldLabel = uilabel(app.UIFigure);
            app.VmCutoffEditFieldLabel.Text = 'Vm Cutoff';
            app.VmCutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.VmCutoffEditField.Tooltip = {'Cutoff for membrane potential (in millivolts)'};
            app.VmCutoffEditField.Value = -25;
            app.FirstDerivativeCutoffEditFieldLabel = uilabel(app.UIFigure);
            app.FirstDerivativeCutoffEditFieldLabel.Text = 'First Derivative Cutoff';
            app.FirstDerivativeCutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.FirstDerivativeCutoffEditField.Tooltip = {'Cutoff for first derivative. Units = mV/SR*1000'};
            app.FirstDerivativeCutoffEditField.Value = 170;
            app.SecondDerivativeCutoffEditFieldLabel = uilabel(app.UIFigure);
            app.SecondDerivativeCutoffEditFieldLabel.Text = 'Second Derivative Cutoff';
            app.SecondDerivativeCutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondDerivativeCutoffEditField.Tooltip = {'Cutoff for second derivative. Units are analogous to those for first derivative.'};
            app.SecondDerivativeCutoffEditField.Value = 3;
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.MovmeanWindowEditFieldLabel = uilabel(app.UIFigure);
            app.MovmeanWindowEditFieldLabel.Text = 'Movmean Window';
            app.MovmeanWindowEditField = uieditfield(app.UIFigure, 'numeric');
            app.MovmeanWindowEditField.Tooltip = {'Window to smooth over for the moving mean function. Units = SR'; ''};
            app.MovmeanWindowEditField.Value = 50;
            app.PlotExampleButton = uibutton(app.UIFigure, 'push');
            app.PlotExampleButton.ButtonPushedFcn = createCallbackFcn(app, @PlotExampleButtonPushed, true);
            app.PlotExampleButton.Text = 'Plot Example';
            app.PlotAllButton = uibutton(app.UIFigure, 'push');
            app.PlotAllButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAllButtonPushed, true);
            app.PlotAllButton.Text = 'Plot All';
            app.AnalyzeLabel = uilabel(app.UIFigure);
            app.AnalyzeLabel.Text = 'Analyze';
            app.ExampleTraceNumberEditFieldLabel = uilabel(app.UIFigure);
            app.ExampleTraceNumberEditFieldLabel.Text = 'Example Trace Number';
            app.ExampleTraceNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExampleTraceNumberEditField.ValueChangedFcn = createCallbackFcn(app, @ExampleTraceNumberEditFieldValueChanged, true);
            app.ExampleTraceNumberEditField.Value = 1;
            app.RunAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ofLabel = uilabel(app.UIFigure);
            app.ofLabel.Text = 'of #';
            app.MainPlot = uiaxes(app.UIFigure);
            title(app.MainPlot, 'Example Trace')
            xlabel(app.MainPlot, 'Timepoint (SR)')
            ylabel(app.MainPlot, 'Vm (mV)')
            zlabel(app.MainPlot, 'Z')
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.PlotAllButton.ContextMenu = app.ContextMenu;
            app.PlotSpecificOrientation = uimenu(app.ContextMenu);
            app.PlotSpecificOrientation.Text = 'PlotSpecificOrientation';
            app.ResetMenu = uimenu(app.ContextMenu);
            app.ResetMenu.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MembranePotentialExtraction_CurrentClamp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end