classdef centerFinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ConfocalCoordinatesMenu    matlab.ui.container.Menu
        SelectPatchCenterDataMenu  matlab.ui.container.Menu
        CalculateTransformedCoordinatesMenu  matlab.ui.container.Menu
        retinanameEditField        matlab.ui.control.EditField
        retinanameEditFieldLabel   matlab.ui.control.Label
        savedDialogue              matlab.ui.control.Label
        enternorthButton           matlab.ui.control.Button
        northY                     matlab.ui.control.NumericEditField
        northX                     matlab.ui.control.NumericEditField
        northpointEditFieldLabel   matlab.ui.control.Label
        printNearestIncision       matlab.ui.control.Label
        printRadius                matlab.ui.control.Label
        printCenter                matlab.ui.control.Label
        subplotSwitch              matlab.ui.control.ToggleSwitch
        savedstatusLamp            matlab.ui.control.Lamp
        savedataButton             matlab.ui.control.Button
        makefiguresButton          matlab.ui.control.Button
        Y12                        matlab.ui.control.NumericEditField
        X12                        matlab.ui.control.NumericEditField
        Y11                        matlab.ui.control.NumericEditField
        X11                        matlab.ui.control.NumericEditField
        Y10                        matlab.ui.control.NumericEditField
        X10                        matlab.ui.control.NumericEditField
        Y9                         matlab.ui.control.NumericEditField
        X9                         matlab.ui.control.NumericEditField
        Y8                         matlab.ui.control.NumericEditField
        X8                         matlab.ui.control.NumericEditField
        Y7                         matlab.ui.control.NumericEditField
        X7                         matlab.ui.control.NumericEditField
        Y6                         matlab.ui.control.NumericEditField
        X6                         matlab.ui.control.NumericEditField
        Y5                         matlab.ui.control.NumericEditField
        X5                         matlab.ui.control.NumericEditField
        Y4                         matlab.ui.control.NumericEditField
        X4                         matlab.ui.control.NumericEditField
        Y3                         matlab.ui.control.NumericEditField
        X3                         matlab.ui.control.NumericEditField
        Y2                         matlab.ui.control.NumericEditField
        X2                         matlab.ui.control.NumericEditField
        Y1                         matlab.ui.control.NumericEditField
        X1                         matlab.ui.control.NumericEditField
        TabGroup                   matlab.ui.container.TabGroup
        Tab                        matlab.ui.container.Tab
        UIAxesTriangulation        matlab.ui.control.UIAxes
        Tab2                       matlab.ui.container.Tab
        UIAxes2Centers             matlab.ui.control.UIAxes
        Tab3                       matlab.ui.container.Tab
        UIAxes3Radii               matlab.ui.control.UIAxes
        IncisionPointLabel         matlab.ui.control.Label
        CheckBox_12                matlab.ui.control.CheckBox
        CheckBox_11                matlab.ui.control.CheckBox
        CheckBox_10                matlab.ui.control.CheckBox
        CheckBox_9                 matlab.ui.control.CheckBox
        CheckBox_8                 matlab.ui.control.CheckBox
        CheckBox_7                 matlab.ui.control.CheckBox
        CheckBox_6                 matlab.ui.control.CheckBox
        CheckBox_5                 matlab.ui.control.CheckBox
        CheckBox_4                 matlab.ui.control.CheckBox
        CheckBox_3                 matlab.ui.control.CheckBox
        CheckBox_2                 matlab.ui.control.CheckBox
        CheckBox_1                 matlab.ui.control.CheckBox
        Label_12                   matlab.ui.control.Label
        Label_11                   matlab.ui.control.Label
        Label_10                   matlab.ui.control.Label
        Label_9                    matlab.ui.control.Label
        Label_8                    matlab.ui.control.Label
        Label_7                    matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        YLabel                     matlab.ui.control.Label
        XLabel                     matlab.ui.control.Label
        GoButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        patchCenterData % centderData structure from patch experiment
    end
    
    methods (Access = private)
        
        function transformedData = coordinateTransformer(~, originalCoordinates, centerCoordinates)
            %This helper function transforms coordinate of original
            %coordinates matrix based on centerCoordinates. If no data
            %exists in originalCoordinates it returns an empty array
            %original coordinates should be a Nx2 matrix where column 1 is
            %x coordinate and column 2 is y coordinate for each point.
            %centerCoordinates should be a 1X2 array where first value is x
            %coordinate of center and second value is y coordinate.
            if size(originalCoordinates)>=1
                transformedData = [originalCoordinates(:,1)-centerCoordinates(1), originalCoordinates(:,2)-centerCoordinates(2)];     
            else
                transformedData = [];
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            allPoints = [app.X1.Value, app.Y1.Value;
                            app.X2.Value, app.Y2.Value;
                            app.X3.Value, app.Y3.Value;
                            app.X4.Value, app.Y4.Value;
                            app.X5.Value, app.Y5.Value;
                            app.X6.Value, app.Y6.Value;
                            app.X7.Value, app.Y7.Value;
                            app.X8.Value, app.Y8.Value;
                            app.X9.Value, app.Y9.Value;
                            app.X10.Value, app.Y10.Value;
                            app.X11.Value, app.Y11.Value;
                            app.X12.Value, app.Y12.Value];
            checkValues = [app.CheckBox_1.Value;
                           app.CheckBox_2.Value;
                           app.CheckBox_3.Value;
                           app.CheckBox_4.Value;
                           app.CheckBox_5.Value;
                           app.CheckBox_6.Value;
                           app.CheckBox_7.Value;
                           app.CheckBox_8.Value;
                           app.CheckBox_9.Value;
                           app.CheckBox_10.Value;
                           app.CheckBox_11.Value;
                           app.CheckBox_12.Value];
            
           %Build perimeterPoints and incisionPoints matrices in x, y
           %coordinates based on user input
            perimeterPoints = [];
            incisionPoints = [];
            incisionIndices = [];
            for i = 1:size(allPoints, 1)
                if allPoints(i, 1) == 0 && allPoints(i, 2) == 0
                    continue
                elseif checkValues(i) == 0
                    perimeterPoints = [perimeterPoints; allPoints(i, 1) allPoints(i, 2)];
                else %if above conditions aren't met point must be incision point
                    incisionPoints = [incisionPoints; allPoints(i, 1) allPoints(i, 2)];
                    incisionIndices = [incisionIndices, i];
                end
            end
            
            %the following section was originally written as standalone code
            %in centerFinder.m
            
            %% Triangulate the center of the retina using all possible combinations of perimeterPoints
            indices = ones(size(perimeterPoints, 1),1);
            for i = 2:size(indices)
                indices(i) = i;
            end
            
            connectivityList = combnk(indices, 3); %Building all possible triangles from perimeterPoints
            TR = triangulation(connectivityList, perimeterPoints); %triangulate the data
            [centers, radii] = circumcenter(TR); %find the center and radius of the circumcenters of each triangle in TR
            medianCenter = median(centers); %take median center and radius as that of the retina
            medianRadii = median(radii);
            
            %% Find the incision point that is closest to the center
            
            distances = zeros(size(incisionPoints, 1), 1);
            for i = 1:size(incisionPoints, 1)
                %distance formula between each incsion point and the center
                distances(i) = sqrt(((incisionPoints(i,1)-medianCenter(1))^2) + ((incisionPoints(i,2)-medianCenter(2))^2));
            end
            [m_rad, I_rad] = min(distances); %find the point closest to the center, m, and it's index, I
            
            if size(I_rad, 1)> 0
                minIncisionCoordinates = incisionPoints(I_rad,:);
            else
                minIncisionCoordinates = [];
            end
            
            
            %plot figures in UI
            triplot(TR, 'Parent', app.UIAxesTriangulation)
            scatter(app.UIAxes2Centers, centers(:,1), centers(:,2), 'b')
            hold(app.UIAxes2Centers, 'on')
            scatter(app.UIAxes2Centers, medianCenter(1), medianCenter(2), 'r', 'filled')
            histogram(app.UIAxes3Radii, radii, 'FaceColor', 'b')
            hold(app.UIAxes3Radii, 'on')
            plot(app.UIAxes3Radii, [medianRadii, medianRadii], [0, 30], '--r', 'LineWidth', 3)
            hold(app.UIAxes3Radii, 'off')
            
            %Print results on GUI
            centerText = strcat("Center your coordinates at X=", num2str(medianCenter(1)), ", Y=", num2str(medianCenter(2)));
            radiusText = strcat("The median radius of this retina is ", num2str(medianRadii), "um");
            if size(incisionPoints)>= 1
            nearestIncisionText = strcat("The nearest incision is point ", num2str(incisionIndices(I_rad)), " (", num2str(m_rad), "um)");
            else
                nearestIncisionText = "No incision points indicated";
            end
            
            
            %save data needed to make a figure in a structure stored as the
            %makefiguresButton's .UserData property
            figureStruct.TR = TR;
            figureStruct.medianCenter = medianCenter;
            figureStruct.medianRadii = medianRadii;
            figureStruct.centers = centers;
            figureStruct.radii = radii;
            app.makefiguresButton.UserData = figureStruct;
            
            
            %transform coordinates based on new center using
            %coordinateTransformer helper function.
            transformedPerimeterPoints = coordinateTransformer(app, perimeterPoints, medianCenter);
            transformedIncisionPoints = coordinateTransformer(app, incisionPoints, medianCenter);
            transformedCenters = coordinateTransformer(app, centers, medianCenter);
            transformedMedianCenter = coordinateTransformer(app, medianCenter, medianCenter); %should always return [0,0]
            transformedNearestIncision = coordinateTransformer(app, minIncisionCoordinates, medianCenter);
           
            %save data to save button, which will be written as a file on
            %buttonpush
            centerData.retina_name = app.retinanameEditField.Value;
            centerData.originalCoordinates.perimeterPoints = perimeterPoints;
            centerData.originalCoordinates.IncisionPoints = incisionPoints;
            centerData.originalCoordinates.allTriangulatedCenters = centers;
            centerData.originalCoordinates.medianCenter = medianCenter;
            centerData.originalCoordinates.allRadii = radii;
            centerData.originalCoordinates.medianRadius = medianRadii;
            centerData.originalCoordinates.nearestIncision = minIncisionCoordinates;
            centerData.originalCoordinates.nearestIncisionDistance = m_rad;
            centerData.transformedCoordinates.perimeterPoints = transformedPerimeterPoints;
            centerData.transformedCoordinates.incisionPoints = transformedIncisionPoints;
            centerData.transformedCoordinates.allTriangulatedCenters = transformedCenters;
            centerData.transformedCoordinates.medianCenter = transformedMedianCenter;
            centerData.transformedCoordinates.allRadii = radii;
            centerData.transformedCoordinates.medianRadius = medianRadii;
            centerData.transformedCoordinates.nearestIncision = transformedNearestIncision;
            centerData.transformedCoordinates.nearestIncisionDistance = m_rad;
            
            app.savedataButton.UserData = centerData;
            
            
            %update GUI
            app.printCenter.Text = centerText;
            app.printRadius.Text = radiusText;
            app.printNearestIncision.Text = nearestIncisionText;
            %make next options appear
            app.makefiguresButton.Visible = 1;
            app.subplotSwitch.Visible = 1;
            app.northpointEditFieldLabel.Visible = 1;
            app.northX.Visible = 1;
            app.northY.Visible = 1;
            app.enternorthButton.Visible = 1;
            app.GoButton.Text = 'Update';
            app.savedstatusLamp.Color = [1 0 0]; %show unsaved (red) at each data update
        end

        % Button pushed function: makefiguresButton
        function makefiguresButtonPushed(app, event)
            %unpack figureStruct from makefiguresButton's .UserData
            %property
            figureStruct = app.makefiguresButton.UserData;
            TR = figureStruct.TR;
            medianCenter = figureStruct.medianCenter;
            medianRadii = figureStruct.medianRadii;
            centers = figureStruct.centers;
            radii = figureStruct.radii;
            
            if strcmp(app.subplotSwitch.Value, 'subplots')

                %% Plot the data
                figure('Position', [400 100 800 800])
                subplot(2, 2, 1)
                title('triangulation')
                hold on
                triplot(TR)
                viscircles(medianCenter,medianRadii, 'LineStyle', '--');
                subplot(2,2, 2)
                title('centers')
                hold on
                scatter(centers(:,1), centers(:,2))
                scatter(medianCenter(1), medianCenter(2), 'r', 'filled')
                legend('centers', 'median')
                subplot(2, 2, 3)
                title('radii')
                hold on
                histogram(radii)
                plot([medianRadii, medianRadii], [0, 30], '--r', 'LineWidth', 3)
                hold off
            else
                figure
                title('Triangulation')
                hold on
                viscircles(medianCenter,medianRadii, 'LineStyle', '-');
                triplot(TR, 'o', 'Color', 'k','LineStyle','--', 'LineWidth', 1.4, 'MarkerFaceColor', 'g')
                scatter(centers(:,1), centers(:,2), 60, 'MarkerEdgeColor', 'c', 'MarkerFaceColor', [0.2 0.2 0.2])
                scatter(medianCenter(1), medianCenter(2), 60, 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'y')
                legend('Perimeter Points', 'Triangulated Centers', 'Median Center')
                
                figure
                title('centers')
                hold on
                scatter(centers(:,1), centers(:,2))
                scatter(medianCenter(1), medianCenter(2), 'r', 'filled')
                legend('centers', 'median')
                
                figure
                title('Radii')
                hold on
                histogram(radii)
                plot([medianRadii, medianRadii], [0, 30], '--r', 'LineWidth', 3)
                xlabel('microns')
                ylabel('count')
                hold off
            end
            
        end

        % Button pushed function: enternorthButton
        function enternorthButtonPushed(app, event)
            %save north data and make save option visible
            
            %save north point in previously built structure
            northPoint = [app.northX.Value, app.northY.Value];
            app.savedataButton.UserData.transformedCoordinates.northPoint = northPoint;
            
            %make save option visible
            app.savedataButton.Visible = 1;
            app.savedstatusLamp.Visible = 1;
            app.enternorthButton.Text = 'Update';
            app.savedstatusLamp.Color = [1 0 0]; %show unsaved (red) at each data update
        end

        % Button pushed function: savedataButton
        function savedataButtonPushed(app, event)
            %saves data and updates button color status
%             selectPath = uigetdir("C:\Users\Uranus\Desktop\scottLab", "Choose directory");
%             fname = inputdlg("enter file name");
%             fname = fname(~isspace(fname)); %automatically remove any spaces in fname
%             fullpath = strcat(selectPath, '\', fname, ".mat");
            
            [fname, fpath] = uiputfile('.mat', 'Save Retina Size Data');
            fullpath = strcat(fpath, fname);
            sizeData = app.savedataButton.UserData;
            
            try %try saving
                save(fullpath, "sizeData")
                app.savedstatusLamp.Color = [0 1 0];
                app.savedDialogue.Visible = 1;
                app.savedDialogue.Text = strcat("Success! Saved data to ", fullpath);
            catch
                app.savedstatusLamp.Color = [1 0 0];
                app.savedDialogue.Visible = 1;
                app.savedDialogue.Text = "Oops! Couldn't save that. Please try again";
            end

        end

        % Menu selected function: SelectPatchCenterDataMenu
        function SelectPatchCenterDataMenuSelected(app, event)
            [f, p] = uigetfile('Select Center Data from Patch Experiment'); %loads file called size data
            load(fullfile(p, f)); %loads sizeData
            app.patchCenterData = sizeData;
        end

        % Menu selected function: CalculateTransformedCoordinatesMenu
        function CalculateTransformedCoordinatesMenuSelected(app, event)
            prompt = {'Enter original X coordinate:','Enter original Y coordinate:'};
            dlgtitle = 'Original Coordinates';
            fieldsize = [1 45; 1 45];
            answer = inputdlg(prompt,dlgtitle,fieldsize);
            xCoord = str2num(answer{1});
            yCoord = str2num(answer{2});

            [theta, rho] = cart2pol(xCoord, yCoord);

            originalRadius = app.patchCenterData.transformedCoordinates.medianRadius;
            currentCenterData = app.savedataButton.UserData;
            currentRadius = currentCenterData.transformedCoordinates.medianRadius;
            rho_adjusted = rho*currentRadius/originalRadius; 

            [x_adjusted, y_adjusted] = pol2cart(theta, rho_adjusted);
            
            x_actual = x_adjusted + currentCenterData.originalCoordinates.medianCenter(1);
            y_actual = y_adjusted + currentCenterData.originalCoordinates.medianCenter(2);
            disp(['Confocal Coordinates: X = ' num2str(x_actual), ' Y = ' num2str(y_actual)])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ConfocalCoordinatesMenu = uimenu(app.UIFigure);
            app.ConfocalCoordinatesMenu.Text = 'Confocal Coordinates';
            app.SelectPatchCenterDataMenu = uimenu(app.ConfocalCoordinatesMenu);
            app.SelectPatchCenterDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectPatchCenterDataMenuSelected, true);
            app.SelectPatchCenterDataMenu.Text = 'Select Patch Center Data';
            app.CalculateTransformedCoordinatesMenu = uimenu(app.ConfocalCoordinatesMenu);
            app.CalculateTransformedCoordinatesMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateTransformedCoordinatesMenuSelected, true);
            app.CalculateTransformedCoordinatesMenu.Text = 'Calculate Transformed Coordinates';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1.';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2.';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3.';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '4.';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '5.';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '6.';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '7.';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '8.';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '9.';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '10.';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '11.';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '12.';
            app.CheckBox_1 = uicheckbox(app.UIFigure);
            app.CheckBox_1.Text = '';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.UIFigure);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.UIFigure);
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.UIFigure);
            app.CheckBox_6.Text = '';
            app.CheckBox_7 = uicheckbox(app.UIFigure);
            app.CheckBox_7.Text = '';
            app.CheckBox_8 = uicheckbox(app.UIFigure);
            app.CheckBox_8.Text = '';
            app.CheckBox_9 = uicheckbox(app.UIFigure);
            app.CheckBox_9.Text = '';
            app.CheckBox_10 = uicheckbox(app.UIFigure);
            app.CheckBox_10.Text = '';
            app.CheckBox_11 = uicheckbox(app.UIFigure);
            app.CheckBox_11.Text = '';
            app.CheckBox_12 = uicheckbox(app.UIFigure);
            app.CheckBox_12.Text = '';
            app.IncisionPointLabel = uilabel(app.UIFigure);
            app.IncisionPointLabel.Text = 'Incision Point?';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxesTriangulation = uiaxes(app.Tab);
            title(app.UIAxesTriangulation, 'Triangulation')
            xlabel(app.UIAxesTriangulation, 'X')
            ylabel(app.UIAxesTriangulation, 'Y')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes2Centers = uiaxes(app.Tab2);
            title(app.UIAxes2Centers, 'Centers')
            xlabel(app.UIAxes2Centers, 'X')
            ylabel(app.UIAxes2Centers, 'Y')
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UIAxes3Radii = uiaxes(app.Tab3);
            title(app.UIAxes3Radii, 'Radii')
            xlabel(app.UIAxes3Radii, 'X')
            ylabel(app.UIAxes3Radii, 'Y')
            app.X1 = uieditfield(app.UIFigure, 'numeric');
            app.Y1 = uieditfield(app.UIFigure, 'numeric');
            app.X2 = uieditfield(app.UIFigure, 'numeric');
            app.Y2 = uieditfield(app.UIFigure, 'numeric');
            app.X3 = uieditfield(app.UIFigure, 'numeric');
            app.Y3 = uieditfield(app.UIFigure, 'numeric');
            app.X4 = uieditfield(app.UIFigure, 'numeric');
            app.Y4 = uieditfield(app.UIFigure, 'numeric');
            app.X5 = uieditfield(app.UIFigure, 'numeric');
            app.Y5 = uieditfield(app.UIFigure, 'numeric');
            app.X6 = uieditfield(app.UIFigure, 'numeric');
            app.Y6 = uieditfield(app.UIFigure, 'numeric');
            app.X7 = uieditfield(app.UIFigure, 'numeric');
            app.Y7 = uieditfield(app.UIFigure, 'numeric');
            app.X8 = uieditfield(app.UIFigure, 'numeric');
            app.Y8 = uieditfield(app.UIFigure, 'numeric');
            app.X9 = uieditfield(app.UIFigure, 'numeric');
            app.Y9 = uieditfield(app.UIFigure, 'numeric');
            app.X10 = uieditfield(app.UIFigure, 'numeric');
            app.Y10 = uieditfield(app.UIFigure, 'numeric');
            app.X11 = uieditfield(app.UIFigure, 'numeric');
            app.Y11 = uieditfield(app.UIFigure, 'numeric');
            app.X12 = uieditfield(app.UIFigure, 'numeric');
            app.Y12 = uieditfield(app.UIFigure, 'numeric');
            app.makefiguresButton = uibutton(app.UIFigure, 'push');
            app.makefiguresButton.ButtonPushedFcn = createCallbackFcn(app, @makefiguresButtonPushed, true);
            app.makefiguresButton.Visible = 'off';
            app.makefiguresButton.Text = 'make figures';
            app.savedataButton = uibutton(app.UIFigure, 'push');
            app.savedataButton.ButtonPushedFcn = createCallbackFcn(app, @savedataButtonPushed, true);
            app.savedataButton.Visible = 'off';
            app.savedataButton.Text = 'save data';
            app.savedstatusLamp = uilamp(app.UIFigure);
            app.savedstatusLamp.Visible = 'off';
            app.subplotSwitch = uiswitch(app.UIFigure, 'toggle');
            app.subplotSwitch.Items = {'figures', 'subplots'};
            app.subplotSwitch.Visible = 'off';
            app.subplotSwitch.Value = 'figures';
            app.printCenter = uilabel(app.UIFigure);
            app.printCenter.Text = '';
            app.printRadius = uilabel(app.UIFigure);
            app.printRadius.Text = '';
            app.printNearestIncision = uilabel(app.UIFigure);
            app.printNearestIncision.Text = '';
            app.northpointEditFieldLabel = uilabel(app.UIFigure);
            app.northpointEditFieldLabel.Visible = 'off';
            app.northpointEditFieldLabel.Text = 'north point';
            app.northX = uieditfield(app.UIFigure, 'numeric');
            app.northX.Visible = 'off';
            app.northY = uieditfield(app.UIFigure, 'numeric');
            app.northY.Visible = 'off';
            app.enternorthButton = uibutton(app.UIFigure, 'push');
            app.enternorthButton.ButtonPushedFcn = createCallbackFcn(app, @enternorthButtonPushed, true);
            app.enternorthButton.Visible = 'off';
            app.enternorthButton.Text = 'enter north';
            app.savedDialogue = uilabel(app.UIFigure);
            app.savedDialogue.Visible = 'off';
            app.savedDialogue.Text = '';
            app.retinanameEditFieldLabel = uilabel(app.UIFigure);
            app.retinanameEditFieldLabel.Text = 'retina name';
            app.retinanameEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = centerFinder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end