classdef physiologyPipeline < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        InfoMenu                   matlab.ui.container.Menu
        AboutMenu                  matlab.ui.container.Menu
        InstructionsMenu           matlab.ui.container.Menu
        OptionsMenu                matlab.ui.container.Menu
        ProjectMenu                matlab.ui.container.Menu
        ShowAllCellsMenu           matlab.ui.container.Menu
        CheckChangesMenu           matlab.ui.container.Menu
        PrintDescriptionMenu       matlab.ui.container.Menu
        SyncMenu                   matlab.ui.container.Menu
        TagsMenu                   matlab.ui.container.Menu
        ViewTagsThisProjectMenu    matlab.ui.container.Menu
        AddTagsThisProjectMenu     matlab.ui.container.Menu
        RemoveTagsThisProjectMenu  matlab.ui.container.Menu
        ViewTagsAllProjectsMenu    matlab.ui.container.Menu
        EditCellsProjectsMenu      matlab.ui.container.Menu
        AdvancedMenu               matlab.ui.container.Menu
        DeleteAProjectMenu         matlab.ui.container.Menu
        SetSharedDirMenu           matlab.ui.container.Menu
        EditPreviousMenu           matlab.ui.container.Menu
        ChangeRecordingInfoMenu    matlab.ui.container.Menu
        EditACellMenu              matlab.ui.container.Menu
        UpdateProjectsMenu         matlab.ui.container.Menu
        AmpButton                  matlab.ui.control.Button
        RecordingInfoButton        matlab.ui.control.Button
        CurrentProjectLabel        matlab.ui.control.Label
        PhysiologyAnalysisPipelineLabel  matlab.ui.control.Label
        LoadRecordingsButton       matlab.ui.control.Button
        LoadProjectButton          matlab.ui.control.Button
        CreateProjectButton        matlab.ui.control.Button
        ContextMenu                matlab.ui.container.ContextMenu
        AssignFromPreviousMenu     matlab.ui.container.Menu
    end

    
    properties (Access = public)
        appDirectory % string containing the local path to the directory that this app is running in. Assigned during startup
        sharedDirectory = 'physiologyPipeline'; %This is the name of the greatest shared directory. The pipeline should be somewhere INSIDE this directory. Can be updated using the options menu.
        localPathToSharedDir %Path from the local machines base directory to app.sharedDirectory
        allProjects %structure of all project names
        cellList
        projectInfo
        RecordingInformation %structure containing basic information about the recording that will be analyzed next
        stimulusIDStruct %structure containing the stimulusID data
        dataHolder = struct(); %blank struct that is just used to pass data back and forth between some callbacks
        numDevices = 1; %number of amplifiers to look for when exporting clarinet data
    end
    
    properties (Access = private)
    end
    
    methods (Access = private)
        
        function updateAllProjects(app)
            %Updates the allProjects structure
            app.allProjects.projects.(app.projectInfo.projectName).projectInfo = app.projectInfo;
            app.allProjects.lastUpdate = datetime();
            allProjects = app.allProjects;
            save(changePathSlashesUnix(fullfile(app.appDirectory, '/appData/allProjects.mat')), 'allProjects') %save a new entry in the allProjects file
        end
        
        function  [tagExists, sameDescription, previousDescription] = checkTagsAllProjects(app, tag, description)
            %check whether the input 'tag' (a string) exists for any other
            %project. If so tagExists = 1. Then check if the description is
            %the same as was entered by the user. If so, sameDescription
            %=1; Previous description is a string specifying exactly what
            %the previous description was.
            tagExists = 0;
            sameDescription = 0;
            previousDescription = '';
            projectNames = fieldnames(app.allProjects.projects);
            for i = 1:numel(projectNames)
                project_i = projectNames{i};
                info_i = app.allProjects.projects.(project_i).projectInfo;
                if isfield(info_i, 'tags')
                    for j = 1:size(info_i.tags, 1)
                        tag_j = info_i.tags{j, 1};
                        description_j = info_i.tags{j, 2};
                        if strcmp(tag_j, tag)
                            tagExists = 1;
                            disp(['A tag with this name has been used before in a project with the name ' project_i])
                            previousDescription = description_j;
                            if strcmp(description_j, description)
                                sameDescription = 1;
                            end
                            return
                        end
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            p = mfilename('fullpath');
            disp(['Running Phyiology Pipeline App From: ' newline p])
            if ispc
                disp('Windows PC detected')
                slashes = strfind(p, '\');
            elseif isunix
                disp('Unix system detected');
                slashes = strfind(p, '/');
            end
            lastSlash = slashes(end);
            app.appDirectory = p(1:lastSlash);
            
            %find the path to the sharedDirectory
            sharedDirectoryLocation = strfind(app.appDirectory, app.sharedDirectory);
            if isempty(sharedDirectoryLocation)
                disp(['WARNING: Unable to locate shared directory.'...
                    ' The pipeline app should be INSIDE of the shared directory. Update the shared directory using Options -> Advanced -> Set Shared Dir'])
            else
                app.localPathToSharedDir = app.appDirectory(1:sharedDirectoryLocation+numel(app.sharedDirectory));
                disp(['Shared directory path set to ' app.localPathToSharedDir])
            end
            
            %load the data containing a short list of all projects
            load(changePathSlashesUnix(fullfile(app.appDirectory, '/appData/allProjects.mat'))) %loads a struct called allProjects to the workspace
            app.allProjects = allProjects;
            
            %Set the animal information to basics
            app.RecordingInformation.ID = '';
            app.RecordingInformation.Genotype = '';
            app.RecordingInformation.DoB = datetime('now');
            app.RecordingInformation.Sex = '';
            app.RecordingInformation.LabID = '';
            app.RecordingInformation.CageNumber = '';
            app.RecordingInformation.Treatments = '';
            app.RecordingInformation.Tags = '';
            app.RecordingInformation.ExperimentalGroup = '';
            app.RecordingInformation.Rig = '';
            app.RecordingInformation.RecordingDate = datetime('now');
            app.RecordingInformation.ExperimenterName = '';
            app.RecordingInformation.AnalyzerName = '';
        end

        % Menu selected function: DeleteAProjectMenu
        function DeleteAProjectMenuSelected(app, event)
            projectNames = fieldnames(app.allProjects.projects);
            
            changePathSlashesUnix(fullfile(app.appDirectory, '/appData/allProjects.mat'));
            
            [indx, tf] = listdlg('ListString', projectNames, 'PromptString', 'Select a project to load', 'SelectionMode', 'single');
            if ~tf
                disp('Canceled project deletion')
                return
            end
            
            YN = uiconfirm(app.UIFigure,['WARNING: You are about to PERMINANTLY delete ' ...
                'a project. This action cannot '...
                'be undone. Are you sure you would like to proceed?'], 'Confirm Deletion');
            
            if ~strcmpi(YN,'OK')
                disp('Canceled project deletion')
                return
            end
            
            %remove the project
            [~, f, ext] = fileparts(app.allProjects.projects.(projectNames{indx}).projectInfo.batchListFName);
            cd(changePathSlashesUnix(app.localPathToSharedDir))
            p = app.allProjects.projects.(projectNames{indx}).projectInfo.relativePathFromPipeline;
            batchFile = fullfile(p, [f ext]);
            load(changePathSlashesUnix(batchFile)) %loads cellList
            app.allProjects.projects = rmfield(app.allProjects.projects, projectNames{indx});
            app.allProjects.lastUpdate = datetime();
            allProjects = app.allProjects;
            save(changePathSlashesUnix(fullfile(app.appDirectory, '/appData/allProjects.mat')), 'allProjects') %save a new entry in the allProjects file
            
            disp(['Deleted project with name ' projectNames{indx}])
            disp(['The batch file still exists at ' batchFile ' you must delete this manually'])
            disp('The following cells were associated with this project, their CellParameters structures and analyses still exist (see the batch file for file locations):')
            disp(cellList(:, 1))
            
        end

        % Menu selected function: SetSharedDirMenu
        function SetSharedDirMenuSelected(app, event)
            sharedDirectoryLocation = changePathSlashesUnix(uigetdir(app.appDirectory, 'Select shared directory'));
            if isempty(sharedDirectoryLocation)
                disp(['WARNING: Unable to locate shared directory.'...
                    'The pipeline app should be INSIDE of the shared directory. Update the shared directory using Options -> Advanced -> Set Shared Dir'])
            end
            app.localPathToSharedDir = app.appDirectory(1:sharedDirectoryLocation+numel(app.sharedDirectory));
        end

        % Menu selected function: AboutMenu
        function AboutMenuPushed(app, event)
            web(changePathSlashesUnix(fullfile(app.appDirectory, '/appInfo/about.html')), '-browser')
        end

        % Menu selected function: InstructionsMenu
        function InstructionsMenuSelected(app, event)
            web(changePathSlashesUnix(fullfile(app.appDirectory, '/appInfo/instructions.html')), '-browser')
        end

        % Button pushed function: CreateProjectButton
        function CreateProjectButtonPushed(app, event)
            % user inputs new project name
            proposedName = inputdlg('Enter the project name (no special characters or spaces)', 'Project Name',[1, 70], {'camelCaseHere'});
            if isempty(proposedName)
                warndlg('No project was created')
                return
            end
            
            % reformat name
            projectName = proposedName{1};
            projectName(isspace(projectName)) = ''; %remove spaces
            if contains(projectName, '\') || contains(projectName, '/') || contains(projectName, '.')
                warndlg('Could not create the project because special characters were used in the name. Try again without special characters.')
                return
            end
            
            % check if this name has been used before
            previousProjectNames = fieldnames(app.allProjects.projects);
            if ismember(projectName, previousProjectNames)
                warndlg('Could not create the project because a project with this name already exists. Try again with a new project name')
                return
            end
            
            % user inputs the project creator's name
            creator = inputdlg("Enter the creator's name", 'Creator Name',[1, 70]);
            if isempty(proposedName)
                warndlg('No creator was specified. Project not created')
                return
            end

            % add a description to the project (this feature was added
            % 7/30/2024)
            description = inputdlg("Enter a description for the project", 'Description',[4, 70]);
            if isempty(proposedName)
                warndlg('No description was was specified')
                description = '';
            end
            
            % user selects a directory to save the project in
            selpath = uigetdir('','Select folder to save project');
            dirName = fullfile(selpath, [projectName, '_physiologyBatch']);
            mkdir(dirName) %make a new directory at the appropriate location
            
            cellList = cell(0, 2); %initiate the cellList (nx2 cell array)
            fName = [projectName, '_physiologyBatch.mat'];
            save(changePathSlashesUnix(fullfile(dirName, fName)), 'cellList') %save the blank cell list
            
            app.cellList = cellList;
            
            %Create an information file
            projectInfo = struct();
            projectInfo.projectName = projectName;
            projectInfo.projectLocation = dirName;
            projectInfo.batchListFName = fullfile(dirName, fName);
            projectInfo.relativePathFromPipeline = relativepath(dirName, app.appDirectory); %this will only work if everything is kept within box
            projectInfo.creationDate = datetime();
            projectInfo.creator = creator;
            projectInfo.description = description;
            projectInfo.lastUpdate = datetime();
            projectInfo.tags = cell(0, 2);
            
            fNameInfo = [projectName '_physiologyInfo.mat'];
            save(changePathSlashesUnix(fullfile(dirName, fNameInfo)), 'projectInfo')
            
            app.projectInfo = projectInfo;
            
            %update all projects
            app.updateAllProjects();
            
            %prepare the app
            app.RecordingInfoButton.Enable = 1;
            app.AddTagsThisProjectMenu.Enable = 1;
            app.ViewTagsThisProjectMenu.Enable = 1;
            app.RemoveTagsThisProjectMenu.Enable = 1;
            app.ProjectMenu.Enable = 1;
            app.EditPreviousMenu.Enable = 1;
            
            app.CurrentProjectLabel.Text = ['Current Project: ' newline projectName];
            disp(['Successfully created new project at:', newline dirName])
            
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: LoadProjectButton
        function LoadProjectButtonPushed(app, event)
            projectNames = fieldnames(app.allProjects.projects);
            
            projectNames{end+1} = 'BROWSE';
            
            [indx, tf] = listdlg('ListString', projectNames, 'PromptString', 'Select a project to load', 'SelectionMode', 'single');
            
            if ~tf
                disp('Canceled project load')
                return
            end
            
            if indx < numel(projectNames)
                d = app.allProjects.projects.(projectNames{indx}).projectInfo.relativePathFromPipeline;
                p = changePathSlashesUnix(fullfile(app.localPathToSharedDir, d(2:end)));
            elseif indx == numel(projectNames) %browse was selected
                p = changePathSlashesUnix(uigetdir('Select the batch directory'));
                
                if p==0
                    disp('No project was loaded')
                    return
                end
            end
            
            %pathparts = strsplit(p,filesep);
            pathparts = strsplit(p, {'/', '\'});
            lastDir = pathparts{end};
            projectNameEnd = strfind(lastDir, '_physiologyBatch');
            projectName = lastDir(1:projectNameEnd-1);   
            p_agnostic = replace(p, '\', '/'); %works for both windows and unix
            
            %loads cellList:
            load(changePathSlashesUnix(fullfile(p_agnostic, [projectName '_physiologyBatch.mat']))) %should load a variable with name cellList to the workspace
            
            if ~exist('cellList','var')
                warndlg('Could not load the project. There is something wrong with the batch file you selected. It should contain a variable named batchList')
                return
            end
            
            %load the project info
            load(changePathSlashesUnix(fullfile(p_agnostic, [projectName '_physiologyInfo.mat']))) %should load a structure with name projectInfo to the workspace
            
            if ~exist('projectInfo','var')
                warndlg('Could not load the project. There is something wrong with the project information file. Check that projectInfo.mat is in the same directory as the batch file that you selected')
                return
            end
            
            % get the app ready
            app.cellList = cellList;
            app.projectInfo = projectInfo;
            disp(['Success, loaded project named ' projectName])
            disp(['Last update was ' datestr(app.projectInfo.lastUpdate)])
            
            %prepare the app
            app.RecordingInfoButton.Enable = 1;
            app.AddTagsThisProjectMenu.Enable = 1;
            app.ViewTagsThisProjectMenu.Enable = 1;
            app.RemoveTagsThisProjectMenu.Enable = 1;
            app.ProjectMenu.Enable = 1;
            app.EditPreviousMenu.Enable = 1;
            
            app.CurrentProjectLabel.Text = ['Current Project: ' newline projectName];
            
            drawnow;
            figure(app.UIFigure)
        end

        % Menu selected function: AddTagsThisProjectMenu
        function AddTagsThisProjectMenuSelected(app, event)
            tagInfo = inputdlg({'Tag Name', 'Brief Description'}, 'Add Tag', [1 50]);
            newTag = tagInfo(1);
            newDescription = tagInfo(2);
            if isempty(tagInfo) || isempty(newTag{1})
                disp('Aborting tag add: no input was entered')
                return
            end
            
            %check if the tag already exists for this project
            if contains(newTag, app.projectInfo.tags)
                disp('Warning: A tag with this name already exists for this project so a new tag will not be added')
                return
            end
            
            %check if the tag already exists for a previous project
            [tagExists, sameDescription, previousDescription] = app.checkTagsAllProjects(newTag, newDescription);
            if tagExists && ~sameDescription
                answer = questdlg(['This tag has been used previously but with a different description:' newline '"'...
                    previousDescription '"' newline 'You may either ABORT this tag or SWITCH DESCRIPTIONS to the existing one'],...
                    'Tag Exists', 'ABORT', 'SWITCH DESCRIPTIONS', 'ABORT');
                if isempty(answer) || strcmp(answer, 'ABORT')
                    disp('The tag was not added')
                    return
                else
                    newDescription = {previousDescription};
                    disp(['Changed tag description to: "' previousDescription '"'])
                end
            elseif tagExists
                disp('This tag was found with the same description in a previous project')
            end
            
            app.projectInfo.tags(end+1, 1) = newTag;
            app.projectInfo.tags(end, 2) = newDescription;
            
            %sort tags alphabetically
            [tagsSorted, leaf] = sort(app.projectInfo.tags(:, 1));
            descriptionsSorted = app.projectInfo.tags(leaf, 2);
            app.projectInfo.tags = [tagsSorted, descriptionsSorted];
            app.projectInfo.lastUpdate = datetime();
            
            %update and save the allProjects structure
            app.updateAllProjects();


            %save the projectInfo structure
            fNameInfo = [app.projectInfo.projectName '_physiologyInfo.mat'];
            saveLoc = changePathSlashesUnix(fullfile(app.localPathToSharedDir,...
                app.projectInfo.relativePathFromPipeline, fNameInfo));
            projectInfo  = app.projectInfo;
            save(saveLoc, 'projectInfo')
            
            disp(['Successfully added tag ' newTag{1} ' to the project and saved the update'])
        end

        % Menu selected function: ViewTagsThisProjectMenu
        function ViewTagsThisProjectMenuSelected(app, event)
            allTags = app.projectInfo.tags;
            disp(['List of all tags for project ' app.projectInfo.projectName ':'])
            for i = 1:size(allTags, 1)
                disp([num2str(i) ') ' allTags{i, 1} ': ' allTags{i, 2}])
            end
        end

        % Menu selected function: ViewTagsAllProjectsMenu
        function ViewTagsAllProjectsMenuSelected(app, event)
            projectNames = fieldnames(app.allProjects.projects);
            for i = 1:numel(projectNames)
                project_i = projectNames{i};
                disp(['Tags for project "' project_i '"'])
                info_i = app.allProjects.projects.(project_i).projectInfo;
                if isfield(info_i, 'tags')
                    for j = 1:size(info_i.tags, 1)
                        tag_j = info_i.tags{j, 1};
                        description_j = info_i.tags{j, 2};
                        disp(['   ' num2str(j) ')' tag_j ': ' description_j])
                    end
                else
                    disp('     No tags found')
                end
            end
        end

        % Menu selected function: RemoveTagsThisProjectMenu
        function RemoveTagsThisProjectMenuSelected(app, event)
            allTagsAndDescriptions = app.projectInfo.tags;
            allTags = allTagsAndDescriptions(:, 1);
            [selection, OK] = listdlg('PromptString', 'Select the tag to remove', 'ListString', allTags,...
                'ListSize', [200 200], 'Name', 'Tag Removal');
            if ~OK
                disp('Tag removal canceled')
                return
            end
            for i = 1:numel(selection)
                allTagsAndDescriptions(selection(i), :) = []; %remove all tags that were selected (user can select one or multiple tags)
            end
            
            YN = uiconfirm(app.UIFigure,['WARNING: You are about to PERMINANTLY remove ' ...
                num2str(numel(selection)) ' tags from the current project. This action cannot '...
                'be undone. Any tags that are removed WILL NOT be deleted from cells in this project '...
                'that have already contain it. Are you sure you would like to proceed?'], 'Confirm Removal');
            if ~strcmpi(YN,'OK')
                disp('Tag removal canceled')
                return
            end
            
            app.projectInfo.tags = allTagsAndDescriptions;
            app.projectInfo.lastUpdate = datetime();
            
            app.updateAllProjects();
            
            %save the projectInfo structure
            fNameInfo = [app.projectInfo.projectName '_physiologyInfo.mat'];
            saveLoc = changePathSlashesUnix(fullfile(app.localPathToSharedDir,...
                app.projectInfo.relativePathFromPipeline, fNameInfo));
            projectInfo  = app.projectInfo;
            save(saveLoc, 'projectInfo')

            disp(['Successfully removed ' num2str(numel(selection)) ' from the project and saved the update'])
        end

        % Button pushed function: RecordingInfoButton
        function RecordingInfoButtonPushed(app, event)
            %edit the animal information fields
            fields = fieldnames(app.RecordingInformation);
            figWidth = 500;
            figHeight = 30*numel(fields)+60+30;
            fig = uifigure('Position', [500, 200, figWidth, figHeight]);
            lbl = uilabel(fig); lbl.Text = 'Recording and Animal Information'; lbl.FontSize = 14; lbl.Position = [150, figHeight-40, 400, 50];
            fieldHandleArray = {};
            for i = 1:numel(fields)
                field_i = fields{i};
                lbl = uilabel(fig);
                lbl.Text = field_i;
                height_i = figHeight - 60 - 30*(i-1);
                lbl.Position = [10, height_i, 200, 25];
                
                switch field_i
                    case 'DoB'
                        fld = uidatepicker(fig);
                    case 'RecordingDate'
                        fld = uidatepicker(fig);
                    case 'Sex'
                        fld = uidropdown(fig, 'Items', {'', 'F', 'M'});
                    case 'Rig'
                        fld = uidropdown(fig, 'Items', {'', 'E', 'F'});
                    otherwise
                        fld = uieditfield(fig);
                end
                fld.Position = [250, height_i, figWidth-300, 25];
                fld.Value = app.RecordingInformation.(field_i);
                fieldHandleArray{end+1, 1} = field_i;
                fieldHandleArray{end, 2} = fld;
            end
            btn = uibutton(fig, 'Position', [figWidth/2, 10, 100, 30]);
            btn.Text = 'Submit Changes';
            btn.BackgroundColor = [0.3, 0.6, 0.3];
            btn.ButtonPushedFcn = @(btn, event) continueBtnPushed(btn, fig, fieldHandleArray, app);
            
            app.LoadRecordingsButton.Enable = 1;
            function continueBtnPushed(~, fig, fieldHandleArray, app)
                for j = 1:size(fieldHandleArray, 1)
                    fieldHandle_j = fieldHandleArray{j, 2};
                    value_j = fieldHandle_j.Value;
                    field_j = fieldHandleArray{j, 1};
                    app.RecordingInformation.(field_j) = value_j;
                end
                close(fig)
                disp('Recording information has been successfully updated')
            end
        end

        % Button pushed function: LoadRecordingsButton
        function LoadRecordingsButtonPushed(app, event)
            %% 1) Download symphony data and store it in a structure called
            % data (taken from load_PhysiologyData)
            
            %Data should be coming in from Symphony 2. Cells' file names are saved in the
            %form CellName_ClarinetExport.mat. When loaded they add a structure called
            %"epochs" to the workspace. Here we'll load each cell that will be analyzed
            %and save its data to one structure to work with for the analysis.
            
            %User chooses cells (.mat files) to work with, they should all be located in one
            %directory
            [fnames, commonPath] = uigetfile({'*.h5;*.mat','Supported formats (*.h5, *.mat)'; '*.h5','Symphony files (*.h5)';'*.mat','Matlab Exports (*.mat)'}, 'Select cells and center data or select symphony .h5 file', 'MultiSelect', 'on');
            
            [p, f, ext] = fileparts([commonPath, fnames]);
            
            %export .h5 file if user selects it, then give them the option to select
            %clarinet exports again
            if strcmp(ext, '.h5')
                disp('Extracting data. This may take a moment...')
                exportH5toMat_symphony2_PP(f, p, app.numDevices);
                [fnames, commonPath] = uigetfile('*.mat', 'Select cells and center data to analyze', 'MultiSelect', 'on');
            end
            
            if ischar(fnames)
                fnames = {fnames};
            end

            %initialize the data structure
            data = struct();
            
            %load files one at a time, adding them to the data structure where the
            %subfield is the retina name + cell number, eg data.SH13Lc3
            successfulAdds = 0;
            foundCenter = 0;
            for i = 1:size(fnames,2)
                try
                    fileName = fnames{1,i};
                catch
                    fileName = fnames; %if only one file was selected
                end
                
                %first check if the file is data from a cell or is center
                %data
                isCell = regexp(fileName, '^.*(L|R)c\d{1,2}_ClarinetExport.mat$');
                isCenterData = regexp(fileName, '^centerData.*(L|R).mat$');
                
                fullPath = fullfile(commonPath, fileName);
                if isCell %will be 1 if file name matches the format for a cell, otherwise 0
                    load(fullPath) %loads epochs structure for the cell
                    
                    cellNameIndex = regexp(fileName, '^.*(L|R)c\d{1,2}');%regexp for "__L/Rc##"
                    clarinetExportIndex = regexp(fileName, '_ClarinetExport.mat');
                    cellName = fileName(cellNameIndex:clarinetExportIndex-1);
                    
                    if exist('epochs', 'var')%if epochs is a varaible in the workspace (it should be)
                        
                        %Sort clarinet epochs so that they are in order
                        %(Some versions of clarinet have a bug where
                        %epochs are not exported in the correct order)
                        sortedEpochs = struct();
                        for j = 1:numel(epochs)
                            for k = 1:numel(epochs)
                                if epochs(k).meta.epochNum == j
                                    sortedEpochs(j).epoch = epochs(k).epoch;
                                    sortedEpochs(j).meta = epochs(k).meta;
                                    break
                                end
                            end
                        end
                        epochs = sortedEpochs; clear sortedEpochs;
                        
                        data.(cellName).epochs = epochs; %add cell data to the data structure
                        data.(cellName).fileLocation = commonPath; %this is local on the user's machine
                        data.(cellName).relativePathFromPipeline = relativepath(commonPath, app.appDirectory);
                        data.(cellName).relativePathFromProjectBatch = relativepath(commonPath, app.projectInfo.projectLocation);
                        disp(['Found data for ' fileName])
                        clear('epochs')
                        successfulAdds = successfulAdds + 1;
                        
                    else %Notify user if epochs does not exist for some reason.
                        warning(['Could not access recording data for ' fullPath...
                            ' because the file does not contain a matlab variable named epochs'])
                        warndlg(['Could not access recording data for ' fullPath...
                            ' because the file does not contain a matlab variable named epochs'])
                    end
                    
                elseif isCenterData
                    if foundCenter %executes if more than one centerData file was selected
                        warning(['More than one center data file was found.' newline...
                            fullPath ' was NOT ADDED to the data structure'])
                        warndlg(['More than one center data file was found.' newline...
                            fullPath ' was NOT ADDED to the data structure' newline...
                            'Remember to analyze only one recording at a time.'])
                    else
                        load(fullPath) %loads a variable called sizeData
                        data.('centerData') = sizeData; %add sizeData to the data structure in field 'centerData'
                        clear('sizeData')
                        foundCenter = 1;
                        disp(['Found center data ' fileName])
                    end
                else
                    warning(['Did not grab data from ', fullPath,...
                        ' because this file was not identified as a cell or centerData']);
                end
            end
            
            if foundCenter && successfulAdds > 0
                disp(['Success! Added data from ' num2str(successfulAdds) ' cells'])
            elseif foundCenter == 0 && successfulAdds > 0
                data.('centerData') = struct();
                disp('Center data is empty for these cells. Ignore this message if you are not using cell coordinates.')
            else
                error('Improper files selected. Either no cells or no centerData was selected. Check the cell file name fits the regular expression "^.*(L|R)c\d{1,2}_ClarinetExport.mat$"')
            end
            
            
            %% 2) Split and organize the data (taken from determineEpochsPerCell)
            splitOnTheseParameters = {'displayName', 'epochGroupLabel', 'onlineAnalysis',...
                'preTime', 'stimTime', 'tailTime', 'numChecksX', 'numChecksY', 'stixelSize'...
                'randomLabel'};
            
            cells = fieldnames(data);
            for i = 1:size(cells, 1)
                cell_i = cells{i, 1};
                
                if ~strcmp(cell_i, 'centerData') %one field may be centerData, don't want to look at this one now
                    
                    epochLog = {};
                    
                    %fill a cell array with all the parameters the user wants to split
                    %on
                    lastEpochParameters = cell(size(splitOnTheseParameters));
                    for j = 1:numel(splitOnTheseParameters)
                        p = splitOnTheseParameters{j};
                        try
                            lastEpochParameters{j} = data.(cell_i).epochs(1).meta.(p);
                        catch
                            %warning([p ' does not exist for all epochs in ' cell_i])
                            lastEpochParameters{j} = 'unknown'; %won't split on two adjacent epochs with unknowns b/c they both have "unknown" as the value
                        end
                    end
                    
                    lastEpoch_Index = 1;
                    
                    %% loop through each epoch in the structure for the current cell
                    
                    %first check edge case for a cell that only has one
                    %epoch
                    if numel(data.(cell_i).epochs) == 1
                        displayName = data.(cell_i).epochs(1).meta.displayName;
                        epochLog = {displayName;[1 1]};
                    end

                    for j = 2:numel(data.(cell_i).epochs)
                        currentEpochParameters = cell(size(splitOnTheseParameters));
                        
                        for k = 1:numel(splitOnTheseParameters)
                            p = splitOnTheseParameters{k};
                            try
                                currentEpochParameters{k} = data.(cell_i).epochs(j).meta.(p);
                            catch
                                %warning([p ' does not exist for all epochs in ' cell_i])
                                currentEpochParameters{k} = 'unknown'; %won't split on two adjacent epochs with unknowns b/c they both have "unknown" as the value
                            end
                        end
                        
                        try
                            numberOfAverages = double(data.(cell_i).epochs(j).meta.numberOfAverages);
                        catch
                            numberOfAverages = 1;
                        end
                        maxEpochsRemainingInGroup = numberOfAverages - (j - lastEpoch_Index)-1;
                        
                        
                        switch data.(cell_i).epochs(j).meta.displayName %'numberOfAverages' has a different meaning for some stimuli so adjust accordingly
                            case 'Led Pulse Family'
                                maxEpochsRemainingInGroup = double(data.(cell_i).epochs(j).meta.numberOfAverages)*...
                                    double(data.(cell_i).epochs(j).meta.pulsesInFamily) - (j - lastEpoch_Index)-1;
                            case 'Pulse Family'
                                maxEpochsRemainingInGroup = double(data.(cell_i).epochs(j).meta.numberOfAverages)*...
                                    double(data.(cell_i).epochs(j).meta.pulsesInFamily) - (j - lastEpoch_Index)-1;
                        end
                        
                        isLast = j == numel(data.(cell_i).epochs);
                        %add to epochLog each time a new group is found and at the end
                        %of the experiment
                        
                        %check to see if any of the last and current are different - if
                        %so, you'll want to split
                        LastAndCurrentParametersAreDifferent = 0;
                        for k = 1:numel(splitOnTheseParameters)
                            par = currentEpochParameters{k};
                            %compare the current and last parameters in different ways
                            %(strcmp or == ) based on their data class
                            if isa(par, 'char')
                                if ~strcmp(par, lastEpochParameters{k})
                                    LastAndCurrentParametersAreDifferent = 1; %update value if discrepency found
                                    break
                                end
                            elseif isa(par,'double')
                                try
                                    if par ~= lastEpochParameters{k}
                                        LastAndCurrentParametersAreDifferent = 1;
                                        break
                                    end
                                catch
                                    LastAndCurrentParametersAreDifferent = 1;
                                    break
                                end
                            end
                        end
                        
                        
                        %% Add to epoch log if a new group was found
                        if LastAndCurrentParametersAreDifferent || isLast || maxEpochsRemainingInGroup < 0
                            %row 1 of the epoch log gives the name of the protocol
                            displayName = data.(cell_i).epochs(j-1).meta.displayName;
                            displayName = strrep(displayName, '  ', ' '); %get rid of double spaces (not sure why these occur but have seen them for some display names before)
                            epochLog{1, end+1} = displayName;
                            %row 2 gives the indexes of the responses for that protocol
                            if ~isLast
                                epochLog{2, end} = [lastEpoch_Index, j-1];
                                
                                %update for next run
                                lastEpochParameters = currentEpochParameters;
                                lastEpoch_Index = j;


                            else %on last, check whether last epoch is same as second to last epoch
                                
                                %simple copy and paste of above code to compare all the
                                %last and current parameters specified by user
                                LastAndCurrentParametersAreDifferent = 0;
                                for k = 1:numel(splitOnTheseParameters)
                                    par = currentEpochParameters{k};
                                    %compare the current and last parameters in different ways
                                    %(strcmp or == ) based on their data class
                                    if isa(par, 'char')
                                        if ~strcmp(par, lastEpochParameters{k})
                                            LastAndCurrentParametersAreDifferent = 1; %update value if discrepency found
                                            break
                                        end
                                    elseif isa(par,'double')
                                        if par ~= lastEpochParameters{k}
                                            LastAndCurrentParametersAreDifferent = 1;
                                            break
                                        end
                                    end
                                end
                                
                                if LastAndCurrentParametersAreDifferent
                                    epochLog{2, end} = [lastEpoch_Index, j-1];
                                    epochLog{1, end+1} = data.(cell_i).epochs(j).meta.displayName; %last epoch != second to last epoch
                                    epochLog{2, end} = [j, j];
                                else %if different, you need to add two enteries to the epoch log
                                    epochLog{2, end} = [lastEpoch_Index, j]; %if same, last index is j
                                end
                            end
                        end
                    end
                    
                    %% add the epochLog to data for the current cell
                    data.(cell_i).('epochLog') = epochLog;
                end
            end
            
            %% 3) Compute the polar coordinates of each cell
            data = computeCoordinates_PP(data);
            
            %% 4) Load the stimulusIDs structure
            stimIDPath = changePathSlashesUnix(fullfile(app.localPathToSharedDir, '/appData/stimulusIDs/stimulusIDs_PP.mat'));
            load(stimIDPath) %loads a variable called stimulusIDs
            app.stimulusIDStruct = stimulusIDs;
            
            %% 4) Launch the dataViewer app
            cells = fieldnames(data);
            for i = size(cells, 1):-1:1
                cell_i = cells{i, 1};
                if ~strcmp(cell_i, 'centerData') %one field is centerData, don't want to look at this one here
                    %% User chooses which dataviewer app to run
                    h = dataViewer_PP(data, cell_i, app); %primary analysis completed w/in dataViewerApp
                end
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            YN = uiconfirm(app.UIFigure,'Do you want to close the physiology pipeline? Note: this window should stay open while using the Data Viewer windows.', 'Close request');
            if strcmpi(YN,'OK')
                delete(app)
            end
        end

        % Menu selected function: AssignFromPreviousMenu
        function AssignFromPreviousMenuSelected(app, event)
            [f, p] = uigetfile('*.mat', "Select A Cell's Analysis File");
            load(changePathSlashesUnix(fullfile(p, f))); %loads CellParameters file
            app.RecordingInformation = CellParameters.RecordingInformation;
            disp(['Loaded Recording Information from ' f])
            app.LoadRecordingsButton.Enable = 1;
            app.RecordingInfoButtonPushed();
            drawnow;
            figure(app.UIFigure)
        end

        % Menu selected function: ShowAllCellsMenu
        function ShowAllCellsMenuSelected(app, event)
            if size(app.cellList, 1) == 0
                disp(['No cells have been added to ' app.projectInfo.projectName ' yet.'])
                return
            end
            disp(['Previously analyzed cells in ' app.projectInfo.projectName ':'])
            disp(app.cellList(:, 1))
        end

        % Menu selected function: ChangeRecordingInfoMenu
        function ChangeRecordingInfoMenuSelected(app, event)
            % Change the Recording Info for a cell/s
            p = changePathSlashesUnix(uigetdir(fullfile(app.localPathToSharedDir, 'recordings'), 'Select the folder for the recording you would like to change'));
            
            containments = dir(p);
            cellNames = {};
            for i = 3:size(containments, 1) %skip the first two containments because they are '.' and '..'
                if containments(i).isdir
                    cellNames(end+1) = {containments(i).name};
                end
            end
            [indx, tf] = listdlg('ListString', cellNames, 'PromptString', 'Select all cells that you would like to change the recording info for', 'SelectionMode', 'multiple');
            
            if ~tf
                disp('No cells were selcting, aborting update')
                return
            end
            
            firstCell = changePathSlashesUnix(fullfile(p, cellNames{indx(1)}, [cellNames{indx(1)} '_Analysis.mat']));
            load(firstCell, 'CellParameters') %load the old CellParameters file for the first cell
            oldInfo = CellParameters.RecordingInformation;
            clear CellParameters
            
            %edit the animal information fields
            fields = fieldnames(oldInfo);
            figWidth = 500;
            figHeight = 30*numel(fields)+60+30;
            fig = uifigure('Position', [500, 200, figWidth, figHeight]);
            lbl = uilabel(fig); lbl.Text = ['Recording and Animal Information (drawn from) ' cellNames{indx(1)} ')']; lbl.FontSize = 14; lbl.Position = [150, figHeight-40, 400, 50];
            fieldHandleArray = {};
            for i = 1:numel(fields)
                field_i = fields{i};
                lbl = uilabel(fig);
                lbl.Text = field_i;
                height_i = figHeight - 60 - 30*(i-1);
                lbl.Position = [10, height_i, 200, 25];
                
                switch field_i
                    case 'DoB'
                        fld = uidatepicker(fig);
                    case 'RecordingDate'
                        fld = uidatepicker(fig);
                    case 'Sex'
                        fld = uidropdown(fig, 'Items', {'', 'F', 'M'});
                    case 'Rig'
                        fld = uidropdown(fig, 'Items', {'', 'E', 'F'});
                    otherwise
                        fld = uieditfield(fig);
                end
                fld.Position = [250, height_i, figWidth-300, 25];
                fld.Value = oldInfo.(field_i);
                fieldHandleArray{end+1, 1} = field_i;
                fieldHandleArray{end, 2} = fld;
            end
            app.dataHolder = struct(); %empty out the dataHolder
            btn = uibutton(fig, 'Position', [figWidth/2, 10, 100, 30]);
            btn.Text = 'Submit Changes';
            btn.BackgroundColor = [0.3, 0.6, 0.3];
            btn.ButtonPushedFcn = @(btn, event) continueBtnPushed(btn, fig, fieldHandleArray, app);
            uiwait(fig); %wait for the figure to close
            
            %update each cell that was selected:
            for i = 1:numel(indx)
                cellName_i = cellNames{indx(i)};
                cellPath_i = changePathSlashesUnix(fullfile(p, cellName_i, [cellName_i '_Analysis.mat']));
                load(cellPath_i, 'CellParameters') %load the old CellParameters file for the first cell
                CellParameters.RecordingInformation = app.dataHolder.newInfo;
                save(changePathSlashesUnix(fullfile(p, cellName_i, [cellName_i '_Analysis.mat'])), 'CellParameters');
                disp(['Updated recording information for ' cellName_i])
                clear CellParameters
            end
            disp('Updates complete. Sync the appropriate projects if needed')
            
            function continueBtnPushed(~, fig, fieldHandleArray, app)
                for j = 1:size(fieldHandleArray, 1)
                    fieldHandle_j = fieldHandleArray{j, 2};
                    value_j = fieldHandle_j.Value;
                    field_j = fieldHandleArray{j, 1};
                    app.dataHolder.newInfo.(field_j) = value_j;
                end
                close(fig)
            end
        end

        % Menu selected function: EditACellMenu
        function EditACellMenuSelected(app, event)
            [~,cellListFName] = fileparts(app.projectInfo.batchListFName);
            cellListPathLocal = changePathSlashesUnix(fullfile(app.localPathToSharedDir,...
                app.projectInfo.relativePathFromPipeline, [cellListFName, '.mat']));
            updateACell_PP(app.localPathToSharedDir, cellListPathLocal);
        end

        % Menu selected function: EditCellsProjectsMenu
        function EditCellsProjectsMenuSelected(app, event)
            %Change the projects that a cell is associated with. This means
            %you can either associate a cell with a new project or delete
            %it from an existing one.
            [fName, fPath] = uigetfile('.mat', 'Select Analysis File');
            
            %Check that the user chose the right file
            if ~contains(fName, '_Analysis.mat')
                disp("Aborted function. You must select the cell's _Analysis.mat file")
                disp('Note, you can only use this function if the cell you are editing has already been analyzed and associated with at least one project. Try again.')
                return
            end

            %load the analysis file
            load(fullfile(fPath, fName)); %loads a variable called CellParameters to the workspace
            
            %find the existing projects for this cell
            existingProjects = fieldnames(CellParameters.projects);
            nProjects = numel(existingProjects);
            projectsString = [];
            for i = 1:nProjects
                projectsString = [projectsString existingProjects{i} newline];
            end

            %If there is just 1 existing project, the user must add the
            %cell to a new project before they can delete if from an
            %existing one. If they really want to delete a cell from all
            %projects, they can do so manually or use the option in the
            %"Edit A Cell" menu selection.
            if nProjects < 2
                choice = questdlg(['Found one existing project with name ' existingProjects{1} ' for ' CellParameters.cellID...
                    '. You must add a new project before you can delete one.'], 'Project Edit Choice', 'Add Project', 'Cancel', 'Cancel');
            else
                choice = questdlg(['Found ' num2str(nProjects) ' existing projects for ' CellParameters.cellID ':' ...
                    newline projectsString 'Would you like to add or delete a project?'], 'Project Edit Choice', 'Add Project', 'Delete Project', 'Cancel', 'Cancel');
            end
            

            switch choice
                case 'Cancel'
                    disp('Operation canceled')
                    return
                case 'Add Project'
                    possibleProjects = fieldnames(app.allProjects.projects);
                    [indx, tf] = listdlg('PromptString', 'Select Projects To Add', 'ListString', possibleProjects);
                    if ~tf
                        disp('Operation aported')
                        return
                    end
                    
                    %grab a struct from the cell from an existing project's
                    %cell list that we can modify and then add to every
                    %other project.
                    templatePath = CellParameters.projects.(existingProjects{1}).relativePathFromPipeline;
                    [~, templateF, templateExt] = fileparts(CellParameters.projects.(existingProjects{1}).batchListFName);
                    p = changePathSlashesUnix(fullfile(app.localPathToSharedDir, templatePath(2:end), [templateF templateExt]));
                    load(p); %loads a cellList

                    %loop through cellList to find the user's current cell:
                    cellIndex = find(cellfun(@(x) strcmp(x, CellParameters.cellID), cellList(:, 1)));

                    if numel(cellIndex) > 1
                        disp(['There was a problem. The current cell appears to exist multiple times in the project ' existingProjects{1} '. This is unexpected. Aborting process to avoid replication issues. It is recommended that you manually inspect and edit the cellList.'])
                        return
                    end

                    templateStructForCellLists = cellList{cellIndex, 2};
                    clear cellList

                    %now let's loop through all the projects that the user
                    %wants to add the cell to and add them to the analysis
                    %structure and the template structure
                    for i = 1:numel(indx)
                        project_i = possibleProjects{indx(i)};
                        if ismember(project_i, existingProjects) %check if this cell is already associated with the project
                            disp(['Skipping ' project_i ' because it is already associated with this cell'])
                        end
                            
                        %TAGS: first check if there are tags that this cell has
                        %that the project doesn't have yet. If so, add
                        %these tags to the project before moving on
                        d = app.allProjects.projects.(project_i).projectInfo.relativePathFromPipeline;
                        p = fullfile(app.localPathToSharedDir, d(2:end));
                        p_agnostic = changePathSlashesUnix(p); %change slashes to work for both windows and unix

                        pathparts = strsplit(p, {'/', '\'});
                        lastDir = pathparts{end};
                        projectNameEnd = strfind(lastDir, '_physiologyBatch');
                        projectName = lastDir(1:projectNameEnd-1);
                        
                        %loads projectInfo variable to the workspace. This
                        %will be what you want to edit/add tags to, and
                        %then we'll add the project info back to all the
                        %structs
                        load(fullfile(p_agnostic, [projectName '_physiologyInfo.mat'])) %should load a variable with name projectInfo to the workspace
                        existingTags = projectInfo.tags;
                        cellsTags = CellParameters.Tags; %these are all of the tags that the cell already has
                        for j = 1:size(cellsTags, 1) %loop through the cell's tags. If they don't exist in the project, add them to the project
                            if sum(cellfun(@(x) strcmp(x, cellsTags{j, 1}), existingTags(:, 1))) > 0
                                continue
                            end
                            % if the tag doesn't exist yet, add it to the
                            % projectInfo
                            [~, ~, description] = app.checkTagsAllProjects(cellsTags{j, 1}, '');
                            projectInfo.tags{end+1, 1} = cellsTags{j, 1};
                            projectInfo.tags{end, 2} = description;
                            disp(['Added tag with name ' cellsTags{j, 1} ' to project with name ' project_i '. You may disregard the message above regarding prexisting tags'])
                        end
                        
                        projectInfo.lastUpdate = datetime();

                        %Add the new project to the CellParameters
                        %Structure
                        CellParameters.projects.(project_i) = projectInfo;
                        
                        %Then add the new project to the template
                        %structures to be placed in cell lists
                        templateStructForCellLists.projects.(project_i) = projectInfo;

                        %Save the project info file
                        save(fullfile(p_agnostic, [projectName '_physiologyInfo.mat']), 'projectInfo');
                        
                        %update all projects
                        app.projectInfo = projectInfo;
                        app.updateAllProjects();
                        
                        clear projectInfo
                    end

                    %Ok, now we have updated all of the projects'
                    %projectInfo files. We've also updated the cell's
                    %CellParameters file and cellList template struct with the necessary projects.
                    %Finally, we've updated the allProjects .mat file.
                    
                    %Let's clean up a bit by saving and getting rid of the
                    %CellParameters file:
                    save(fullfile(fPath, fName), 'CellParameters')

                    %Okay, now the last thing to do is to replace the
                    %structure in all of the cell lists with the template
                    %structure

                    %This time, we want to loop through all of the projects
                    %in the cell's new updated template struct to make sure
                    %they all get a copy of the new data. So let's extract
                    %those:
                    allProjectsToUpdate = fieldnames(templateStructForCellLists.projects);
                    for i = 1:numel(allProjectsToUpdate)
                        project_i = allProjectsToUpdate{i};
                        
                        %load the project's cellList
                        d = app.allProjects.projects.(project_i).projectInfo.relativePathFromPipeline;
                        p = fullfile(app.localPathToSharedDir, d(2:end));
                        p_agnostic = changePathSlashesUnix(p); %change slashes to work for both windows and unix

                        pathparts = strsplit(p, {'/', '\'});
                        lastDir = pathparts{end};
                        projectNameEnd = strfind(lastDir, '_physiologyBatch');
                        projectName = lastDir(1:projectNameEnd-1);
                        
                        load(fullfile(p_agnostic, [projectName '_physiologyBatch.mat'])) %loads cellList to the workspace

                        %loop through cellList to find the user's current cell:
                        cellIndex = find(cellfun(@(x) strcmp(x, templateStructForCellLists.cellID), cellList(:, 1)));
                        
                        if numel(cellIndex)==0
                            cellList{end+1, 1} = templateStructForCellLists.cellID;
                            cellList{end, 2} = templateStructForCellLists;
                        elseif numel(cellIndex)==1
                            cellList{cellIndex, 2} = templateStructForCellLists;
                        elseif numel(cellIndex) > 1
                            disp(['There was a problem. The current cell appears to exist multiple times in the project ' existingProjects{1} '. This is unexpected. Aborting process to avoid replication issues. It is recommended that you manually inspect and edit the cellList.'])
                            return
                        end
                        
                        save(fullfile(p_agnostic, [projectName '_physiologyBatch.mat']), 'cellList')
                        clear cellList
                        disp(['Successfully updated the project ' project_i ' for cell ' templateStructForCellLists.cellID])
                    end

                case 'Delete Project'
                    disp('Function not yet implemented. Why dont you give it a try!')
            end

        end

        % Menu selected function: SyncMenu
        function SyncMenuSelected(app, event)
            %Sync the project file with the CellParameters files for its constituent cells
            [indx, tf] = listdlg("ListString", app.cellList(:, 1), 'PromptString', 'Select Cells to Sync');
            if ~tf
                disp('Sync Canceled and Aborted')
                return
            end

            for i = 1:numel(indx)
                struct_i = app.cellList{indx(i), 2};
                analysisFileName = fullfile(app.appDirectory, struct_i.relativePathFromPipeline,...
                    struct_i.cellID, [struct_i.cellID '_Analysis.mat']);
                load(changePathSlashesUnix(analysisFileName), 'CellParameters')
                app.cellList{indx(i), 2} = CellParameters;
                clear CellParameters
                disp(['Successfully synced data for ' struct_i.cellID])
            end

            cellList = app.cellList;
            [~, N] = fileparts(app.projectInfo.batchListFName);
            fullFName = changePathSlashesUnix(fullfile(app.appDirectory, app.projectInfo.relativePathFromPipeline, [N '.mat']));
            save(fullFName, 'cellList')
            disp(['Synced project has been saved as ' fullFName])
            %save the new app.cellList
            app.projectInfo.lastUpdate = datetime();
                        
            %save the projectInfo structure
            fNameInfo = [app.projectInfo.projectName '_physiologyInfo.mat'];
            projectInfo = app.projectInfo;
            save(changePathSlashesUnix(fullfile(app.appDirectory, app.projectInfo.relativePathFromPipeline, fNameInfo)), 'projectInfo')
        end

        % Button pushed function: AmpButton
        function AmpButtonPushed(app, event)
            if app.numDevices == 1
                app.AmpButton.Text = '2 Amp';
                app.numDevices = 2;
            else
                app.AmpButton.Text = '1 Amp';
                app.numDevices = 1;
            end
        end

        % Menu selected function: UpdateProjectsMenu
        function UpdateProjectsMenuSelected(app, event)
            % Used to update all projects for a given cell. Goes through all the projects
            % associated with a cell and adds the newest version of the CellParameters structure
            % to each cellList. Automatically saves the new cellList batch files as
            % well

            %!!!!!!!!!!!!!!!!!!!(note this function may require some
            % modifications to work. In a future version it should be
            % depricated by a general function called 'sync all
            % projects' that goes through and does this automatically
            % for every cell). !!!!!!!!!!!!!!!!!!!!!

            %first select the cell you want to update
            cd(changePathSlashesUnix(fullfile(app.localPathToSharedDir, '/recordings')))
            [fname, fpath] = uigetfile('*.mat', 'Select the cell analysis structure you would like to edit');
            
            %check that the user input an _Analysis File
            if ~contains(fname, '_Analysis.mat')
                disp('Edit aborted. You must select an "_Analysis.mat" file')
                return
            end

            %load the CellParameters and Batch files
            load(fullfile(fpath, fname)) %load CellParameters structure for this cell to the workspace
            cellName = CellParameters.cellID;

            %load projects
            projects = fieldnames(CellParameters.projects);

            if numel(projects)<2
                disp(['No need to sync projects for this cell. It has so far only been associated with one project called ' projects{1} ...
                    '. Use this operation once the cell has been associated with multiple projects'])
                return
            end

            %ask the user to select which projects they would like to
            %update for the cell
            [selections, tf] = listdlg('ListString', projects ,...
                'Name', 'Select an operation', 'ListSize', [300 300], 'SelectionMode', 'multiple');
            
            if ~tf
                disp('Edit aborted')
                return
            end

            selectedProjects = projects(selections);
            for n = 1:numel(selectedProjects)
                p_n = CellParameters.projects.(selectedProjects{n});
                [~, f, ext] = fileparts(p_n.batchListFName);
                batchListFName = changePathSlashesUnix(fullfile(rootPath, p_n.relativePathFromPipeline, [f, ext]));
                load(batchListFName) %loads cellList

                % find where this cell is in cellListIndex
                cellListIndex = NaN;
                for i = 1:size(cellList, 1)
                    if strcmp(cellList{i, 1}, cellName)
                        cellListIndex = i;
                        break
                    end
                end

                if isnan(cellListIndex)
                    disp(['Warning, unable to update cell for project ' selectedProjects{n} '. The project was listed in the analysis structure, but the cell name was not found in the cellList. Advise to resolve this manually.']);
                    return
                end
                cellList{cellListIndex, 2} = CellParameters; %syncs cellList with CellParameters

                %save CellParameters and cellList
                save(batchListFName, 'cellList');
                disp(['Successfully updated the entry in project named ' selectedProjects{n}])
                clear cellList
            end
        end

        % Menu selected function: PrintDescriptionMenu
        function PrintDescriptionMenuSelected(app, event)
            %prints the project description if it exists.
            %project descriptions were created 7/30/2024. Full
            %functionality is not yet implemented - currently users can add
            %a description when creating a new project, but previously
            %existing projects did not have a description. There's not yet
            %an option to add a description to an existing project that
            %doesn't have one.
            if isfield(app.projectInfo, 'description')
                disp(['Description for ' app.projectInfo.projectName ':'])
                disp(app.projectInfo.description)
            else
                disp(['There is no description for ' app.projectInfo.projectName '. Functionality does...' ...
                    ' not yet exist to add a description retroactively to an existing project, but this may be coming soon'])
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InfoMenu = uimenu(app.UIFigure);
            app.InfoMenu.Text = 'Info';
            app.AboutMenu = uimenu(app.InfoMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuPushed, true);
            app.AboutMenu.Text = 'About';
            app.InstructionsMenu = uimenu(app.InfoMenu);
            app.InstructionsMenu.MenuSelectedFcn = createCallbackFcn(app, @InstructionsMenuSelected, true);
            app.InstructionsMenu.Text = 'Instructions';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.ProjectMenu = uimenu(app.OptionsMenu);
            app.ProjectMenu.Enable = 'off';
            app.ProjectMenu.Text = 'Project';
            app.ShowAllCellsMenu = uimenu(app.ProjectMenu);
            app.ShowAllCellsMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowAllCellsMenuSelected, true);
            app.ShowAllCellsMenu.Text = 'Show All Cells';
            app.CheckChangesMenu = uimenu(app.ProjectMenu);
            app.CheckChangesMenu.Tooltip = {'In Development'};
            app.CheckChangesMenu.Enable = 'off';
            app.CheckChangesMenu.Text = 'Check Changes';
            app.PrintDescriptionMenu = uimenu(app.ProjectMenu);
            app.PrintDescriptionMenu.MenuSelectedFcn = createCallbackFcn(app, @PrintDescriptionMenuSelected, true);
            app.PrintDescriptionMenu.Text = 'Print Description';
            app.SyncMenu = uimenu(app.ProjectMenu);
            app.SyncMenu.MenuSelectedFcn = createCallbackFcn(app, @SyncMenuSelected, true);
            app.SyncMenu.Tooltip = {''};
            app.SyncMenu.Text = 'Sync';
            app.TagsMenu = uimenu(app.OptionsMenu);
            app.TagsMenu.Text = 'Tags';
            app.ViewTagsThisProjectMenu = uimenu(app.TagsMenu);
            app.ViewTagsThisProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewTagsThisProjectMenuSelected, true);
            app.ViewTagsThisProjectMenu.Enable = 'off';
            app.ViewTagsThisProjectMenu.Text = 'View Tags This Project';
            app.AddTagsThisProjectMenu = uimenu(app.TagsMenu);
            app.AddTagsThisProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @AddTagsThisProjectMenuSelected, true);
            app.AddTagsThisProjectMenu.Enable = 'off';
            app.AddTagsThisProjectMenu.Text = 'Add Tags This Project';
            app.RemoveTagsThisProjectMenu = uimenu(app.TagsMenu);
            app.RemoveTagsThisProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveTagsThisProjectMenuSelected, true);
            app.RemoveTagsThisProjectMenu.Enable = 'off';
            app.RemoveTagsThisProjectMenu.Text = 'Remove Tags This Project';
            app.ViewTagsAllProjectsMenu = uimenu(app.TagsMenu);
            app.ViewTagsAllProjectsMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewTagsAllProjectsMenuSelected, true);
            app.ViewTagsAllProjectsMenu.Text = 'View Tags All Projects';
            app.EditCellsProjectsMenu = uimenu(app.OptionsMenu);
            app.EditCellsProjectsMenu.MenuSelectedFcn = createCallbackFcn(app, @EditCellsProjectsMenuSelected, true);
            app.EditCellsProjectsMenu.Tooltip = {'Add or remove cells to/from multiple projects.'};
            app.EditCellsProjectsMenu.Text = 'Edit Cells'' Projects';
            app.AdvancedMenu = uimenu(app.OptionsMenu);
            app.AdvancedMenu.Text = 'Advanced';
            app.DeleteAProjectMenu = uimenu(app.AdvancedMenu);
            app.DeleteAProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteAProjectMenuSelected, true);
            app.DeleteAProjectMenu.Text = 'Delete A Project';
            app.SetSharedDirMenu = uimenu(app.AdvancedMenu);
            app.SetSharedDirMenu.MenuSelectedFcn = createCallbackFcn(app, @SetSharedDirMenuSelected, true);
            app.SetSharedDirMenu.Text = 'Set Shared Dir';
            app.EditPreviousMenu = uimenu(app.UIFigure);
            app.EditPreviousMenu.Tooltip = {'In development'};
            app.EditPreviousMenu.Enable = 'off';
            app.EditPreviousMenu.Text = 'Edit Previous';
            app.ChangeRecordingInfoMenu = uimenu(app.EditPreviousMenu);
            app.ChangeRecordingInfoMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeRecordingInfoMenuSelected, true);
            app.ChangeRecordingInfoMenu.Text = 'Change Recording Info';
            app.EditACellMenu = uimenu(app.EditPreviousMenu);
            app.EditACellMenu.MenuSelectedFcn = createCallbackFcn(app, @EditACellMenuSelected, true);
            app.EditACellMenu.Text = 'Edit A Cell';
            app.UpdateProjectsMenu = uimenu(app.EditPreviousMenu);
            app.UpdateProjectsMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateProjectsMenuSelected, true);
            app.UpdateProjectsMenu.Text = 'Update Projects';
            app.CreateProjectButton = uibutton(app.UIFigure, 'push');
            app.CreateProjectButton.ButtonPushedFcn = createCallbackFcn(app, @CreateProjectButtonPushed, true);
            app.CreateProjectButton.Text = 'Create Project';
            app.LoadProjectButton = uibutton(app.UIFigure, 'push');
            app.LoadProjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProjectButtonPushed, true);
            app.LoadProjectButton.Text = 'Load Project';
            app.LoadRecordingsButton = uibutton(app.UIFigure, 'push');
            app.LoadRecordingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRecordingsButtonPushed, true);
            app.LoadRecordingsButton.Enable = 'off';
            app.LoadRecordingsButton.Text = 'Load Recording(s)';
            app.PhysiologyAnalysisPipelineLabel = uilabel(app.UIFigure);
            app.PhysiologyAnalysisPipelineLabel.Text = 'Physiology Analysis Pipeline';
            app.CurrentProjectLabel = uilabel(app.UIFigure);
            app.CurrentProjectLabel.Text = {'Current Project:'; 'none'};
            app.RecordingInfoButton = uibutton(app.UIFigure, 'push');
            app.RecordingInfoButton.ButtonPushedFcn = createCallbackFcn(app, @RecordingInfoButtonPushed, true);
            app.RecordingInfoButton.Enable = 'off';
            app.RecordingInfoButton.Tooltip = {'Add information about this recording/retina'};
            app.RecordingInfoButton.Text = 'Recording Info';
            app.AmpButton = uibutton(app.UIFigure, 'push');
            app.AmpButton.ButtonPushedFcn = createCallbackFcn(app, @AmpButtonPushed, true);
            app.AmpButton.Text = '1 Amp';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.AssignFromPreviousMenu = uimenu(app.ContextMenu);
            app.AssignFromPreviousMenu.MenuSelectedFcn = createCallbackFcn(app, @AssignFromPreviousMenuSelected, true);
            app.AssignFromPreviousMenu.Text = 'Assign From Previous';
            % Assign app.ContextMenu
            app.RecordingInfoButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = physiologyPipeline

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end